classdef CompareApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PositionSlider       matlab.ui.control.Slider
        PositionSliderLabel  matlab.ui.control.Label
        HeightSlider         matlab.ui.control.Slider
        HeightSliderLabel    matlab.ui.control.Label
        loadCountryDropDown  matlab.ui.control.DropDown
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        countries
        % Description
        maxValue % Description
        countryData % Description
    end
    
    methods (Access = private)
        
        function results = removeSpace(app, string)
            results = string(find(~isspace(string)))
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.countries = struct;
            app.countryData = struct;
            app.maxValue = 0;
            xline(app.UIAxes, 40, '--')
            yline(app.UIAxes, 1, '--')

        end

        % Value changed function: loadCountryDropDown
        function loadCountryDropDownValueChanged(app, event)
            value = app.loadCountryDropDown.Value;
            if strcmp(value,'Load')
                return
            end
            country = Country(value,0);
            countryName = app.removeSpace(country.name);

            if (isfield(app.countries, countryName))
                return
            end

            app.countries.(countryName) = country;
            
%             countryDates = country.dates(30:30+199);
%             countryDays = country.days(30:30+199);
            countryDeaths = movmean(1000000 * country.deaths(40:40+150-1)/country.population, 14);
            countryDays = 1:length(countryDeaths);
            countryDeaths(countryDeaths<0) = nan;
            app.countryData.(countryName) = countryDeaths;
            
            if max(countryDeaths) > app.maxValue
                app.maxValue = max(countryDeaths);
            end
            
            hold(app.UIAxes,'on')
            cplot = plot(app.UIAxes, countryDays, countryDeaths, 'Tag', countryName)
            hold(app.UIAxes,'off')
            
%             app.UIAxes.YLim = [0,app.maxValue*1.1];

        end

        % Value changing function: HeightSlider
        function HeightSliderValueChanging(app, event)
            changingValue = event.Value;
            countryName = app.removeSpace(app.loadCountryDropDown.Value);
            line = findobj(app.UIAxes.Children,'Tag', countryName);
            line.YData = app.countryData.(countryName)*changingValue/100;
            
        end

        % Value changing function: PositionSlider
        function PositionSliderValueChanging(app, event)
            changingValue = event.Value;
            countryName = app.removeSpace(app.loadCountryDropDown.Value);
            line = findobj(app.UIAxes.Children,'Tag', countryName);
%             line.XData
             line.XData = (1:150) + round(changingValue);
             round(changingValue)
%             round(1:200 + round(changingValue))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'deceased per 1 milion')
            xlabel(app.UIAxes, 'day')
            ylabel(app.UIAxes, 'number')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 120];
            app.loadCountryDropDown = uidropdown(app.UIFigure);
            app.loadCountryDropDown.Items = {'Load Data', 'Norway', 'Denmark', 'Belgium', 'Spain', 'Italy', 'France', 'Germany', 'Netherlands', 'Austria', 'United Kingdom', 'Sweden'};
            app.loadCountryDropDown.ItemsData = {'Load', 'Norway', 'Denmark', 'Belgium', 'Spain', 'Italy', 'France', 'Germany', 'Netherlands', 'Austria', 'United Kingdom', 'Sweden'};
            app.loadCountryDropDown.ValueChangedFcn = createCallbackFcn(app, @loadCountryDropDownValueChanged, true);
            app.loadCountryDropDown.Value = 'Load';
            app.HeightSliderLabel = uilabel(app.UIFigure);
            app.HeightSliderLabel.Text = 'Height';
            app.HeightSlider = uislider(app.UIFigure);
            app.HeightSlider.Limits = [1 100];
            app.HeightSlider.MajorTicks = [];
            app.HeightSlider.ValueChangingFcn = createCallbackFcn(app, @HeightSliderValueChanging, true);
            app.HeightSlider.MinorTicks = [];
            app.HeightSlider.Value = 100;
            app.PositionSliderLabel = uilabel(app.UIFigure);
            app.PositionSliderLabel.Text = 'Position';
            app.PositionSlider = uislider(app.UIFigure);
            app.PositionSlider.Limits = [-50 50];
            app.PositionSlider.MajorTicks = [];
            app.PositionSlider.ValueChangingFcn = createCallbackFcn(app, @PositionSliderValueChanging, true);
            app.PositionSlider.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CompareApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end