classdef ModelApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        clearSavedModelsButton   matlab.ui.control.Button
        lockYAxis                matlab.ui.control.StateButton
        deceasedStatusLabel      matlab.ui.control.Label
        reStatusLabel            matlab.ui.control.Label
        newInfectedsStatusLabel  matlab.ui.control.Label
        timeMarkerLabel          matlab.ui.control.Label
        rSlopeLabel              matlab.ui.control.Label
        rOnsetLabel              matlab.ui.control.Label
        rEndLabel                matlab.ui.control.Label
        rStartLabel              matlab.ui.control.Label
        rSlopeSpinner            matlab.ui.control.Spinner
        simButton                matlab.ui.control.StateButton
        n0Spinner                matlab.ui.control.Spinner
        simGenerations           matlab.ui.control.Spinner
        rOnsetSpinner            matlab.ui.control.Spinner
        rEndSpinner              matlab.ui.control.Spinner
        rStartSpinner            matlab.ui.control.Spinner
        xZoomSlider              matlab.ui.control.Slider
        Label                    matlab.ui.control.Label
        hideMainModelButton      matlab.ui.control.StateButton
        loadCountryDropDown      matlab.ui.control.DropDown
        saveModelButton          matlab.ui.control.Button
        n0Slider                 matlab.ui.control.Slider
        n0SliderLabel            matlab.ui.control.Label
        simButtonOld             matlab.ui.control.Button
        rEndSlider               matlab.ui.control.Slider
        rStartSlider             matlab.ui.control.Slider
        rOnsetSlider             matlab.ui.control.Slider
        fitButton                matlab.ui.control.Button
        resetButton              matlab.ui.control.Button
        deceasedAxes             matlab.ui.control.UIAxes
        reAxes                   matlab.ui.control.UIAxes
        newInfectedAxes          matlab.ui.control.UIAxes
        rStartContextMenu        matlab.ui.container.ContextMenu
        rStartLockMenu           matlab.ui.container.Menu
        rEndContextMenu          matlab.ui.container.ContextMenu
        rEndEnableMenu           matlab.ui.container.Menu
        rEndUseModelMenu         matlab.ui.container.Menu
        rOnsetContextMenu        matlab.ui.container.ContextMenu
        rOnsetLockMenu           matlab.ui.container.Menu
        rSlopeContextMenu        matlab.ui.container.ContextMenu
        rSlopeUseModelMenu       matlab.ui.container.Menu
    end

    
    properties (Access = private)
        newInfectedsLine% Description
        reLine % Description
        deceasedLine % Description
        mainModel % Description
        simulationBars % Description
        savedModels % Description
        countries % Description
        countryDataLine % Description
        ghandles % Description
    end
    
    methods (Access = private)
        
        function updateModel(app)
            app.reAxes.PositionConstraint = 'innerposition';
            app.newInfectedAxes.PositionConstraint = 'innerposition';
            app.deceasedAxes.PositionConstraint = 'innerposition';
            
            app.ghandles.reLine.YData = app.mainModel.re;
            app.ghandles.newInfectedsLine.YData = app.mainModel.newInfecteds;
            %             app.ghandles.newInfectedsLine.YData = app.mainModel.infecteds;
            app.ghandles.deceasedLine.YData = app.mainModel.deceased;
            
            set(findobj(app.UIFigure, 'Tag', 'onsetMarker'), 'Value', app.mainModel.rOnset);
            set(findobj(app.UIFigure, 'Tag', 'mainModel_zcMarker'), 'Value', app.mainModel.zeroCross);
            
            app.newInfectedsStatusLabel.Text = sprintf('%.3f',sum(app.mainModel.newInfecteds));
            app.deceasedStatusLabel.Text = sprintf('%.3f',sum(app.mainModel.deceased));
            
            if (length(app.savedModels) > 0)
                for model = fieldnames(app.savedModels)'
                    sum(app.mainModel.deceased) / sum(app.savedModels.(model{1}).deceased)
                end
            end
            
        end
        
        function simulationUpdate(app, message, infectedHist, deceasedHist)
            disp(message);
            app.ghandles.simulationBars.YData = infectedHist;
            drawnow;
        end
        
        function setModel(app)
            app.mainModel.rOnset = app.rOnsetSlider.Value;
            app.mainModel.rStart = app.rStart.Value;
            app.mainModel.rEnd = app.rEndSlider.Value;
            app.mainModel.n0 = app.n0Slider.Value;
        end
        
        function results = getModel(app)
            try
                app.rOnsetSlider.Value = app.mainModel.rOnset;
                app.rOnsetSpinner.Value = app.mainModel.rOnset;
                app.rStartSlider.Value = app.mainModel.rStart;
                app.rStartSpinner.Value = app.mainModel.rStart;
                app.rEndSlider.Value = app.mainModel.rEnd;
                app.rEndSpinner.Value = app.mainModel.rEnd;
                app.rSlopeSpinner.Value = app.mainModel.rSlope;
                app.n0Slider.Value = log10(app.mainModel.n0);
                app.n0Spinner.Value = app.mainModel.n0;
            catch
                disp('OUT OF BOUNDS')
            end
        end
        
        function results = iff(app, condition, a, b)
            results = b;
            if condition
                results = a;
            end
        end
        
        function results = saveModel(app, name)
            %             app.savedModels = [app.savedModels, app.mainModel.copy()];
            app.savedModels.(name) = app.mainModel.copy();
            
            colors = get(app.reAxes,'colororder');
            hold([app.reAxes app.newInfectedAxes app.deceasedAxes],"on")
            reLine = plot(app.reAxes,app.mainModel.day,app.mainModel.re,'-','Color', colors(2,:), 'Tag','savedModel_Line');
            newInfectedLine = plot(app.newInfectedAxes,app.mainModel.day,app.mainModel.newInfecteds, '-', 'Color', colors(1,:), 'ButtonDownFcn', @lineCallback, 'Tag','savedModel_Line');
            deceasedLine = plot(app.deceasedAxes,app.mainModel.day,app.mainModel.deceased,'-','Color', 'k' ,'Tag','savedModel_Line');
            app.mainModel.fitData
            if (~isempty(app.mainModel.fitData))
                plot(app.deceasedAxes,app.mainModel.day,app.mainModel.fitData,'-', 'Color', colors(2,:),'Tag','savedModel_Line');
                
                reConfPatch = patch(app.reAxes, [1,2,3], [1,2,3], '.-', 'FaceColor', [0.85,0.33,0.10], 'EdgeColor', 'none', 'FaceAlpha', 0.2, 'Tag', 'savedModel_reConfInt', 'Visible', 'off');
                reConfPatch.Visible = 'on';
                reConfPatch.XData = [app.mainModel.day, fliplr(app.mainModel.day)];
                reConfPatch.YData = [app.mainModel.reUpper', fliplr(app.mainModel.reLower')];
                
                deceasedConfPatch = patch(app.deceasedAxes, [1,2,3], [1,2,3], '.-', 'FaceColor', [0.00,0.45,0.74], 'EdgeColor', 'none', 'FaceAlpha', 0.2, 'Tag', 'savedModel_deceasedConfInt', 'Visible', 'off');
                deceasedConfPatch.Visible = 'on';
                deceasedConfPatch.XData = [app.mainModel.day, fliplr(app.mainModel.day)];
                deceasedConfPatch.YData = [app.mainModel.deceasedUpper', fliplr(app.mainModel.deceasedLower')];
                
                %                 peakXCoord = deceasedLine.XData(deceasedLine.YData == max(deceasedLine.YData)) + 5
                %                 peakYCoord = deceasedLine.YData(deceasedLine.YData == max(deceasedLine.YData)) + 1
                %                 text(app.deceasedAxes, peakXCoord, peakYCoord, name);
                
            end
            hold([app.reAxes app.newInfectedAxes app.deceasedAxes],"off")
            
            app.updateModel();
            
            function lineCallback(src, event)
                src.Parent.Children
            end
        end
        
        function mouseMove(app, src, event)
            if (app.reAxes.CurrentPoint(1,3) == 1 || app.deceasedAxes.CurrentPoint(1,3) == 1)
                try
                    if (isfield(app.countries, app.loadCountryDropDown.Value))
                        app.timeMarkerLabel.Text = sprintf('%s, %s', datestr(app.countries.(app.loadCountryDropDown.Value).dates(floor(app.reAxes.CurrentPoint(1,1))+30)), num2str(round(app.reAxes.CurrentPoint(1,1))));
                    else
                        app.timeMarkerLabel.Text = num2str(round(app.reAxes.CurrentPoint(1,1)));
                    end
                catch
                end
            end
        end
        
        
        function results = timeZoom(app, changingValue)
            app.newInfectedAxes.YLimMode = 'auto';
            app.deceasedAxes.YLimMode = 'auto';
            
            app.reAxes.XLim(2) = changingValue;
            app.newInfectedAxes.XLim(2) = changingValue;
            app.deceasedAxes.XLim(2) = changingValue;
            
            app.rOnsetSlider.Limits = [0 changingValue];
            
            app.reAxes.XTickMode = 'auto';
            app.newInfectedAxes.XTickMode = 'auto';
            app.deceasedAxes.XTickMode = 'auto';
            
            if changingValue == app.mainModel.nDays
                app.newInfectedAxes.YLim = [0 inf];
                app.deceasedAxes.YLim = [0 inf];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
%             echo on all;
            datacursormode(app.UIFigure, 'off');
            set(app.UIFigure, 'WindowButtonMotionFcn', @app.mouseMove);
            
            app.newInfectedAxes.InnerPosition(1) = app.reAxes.InnerPosition(1);
            app.newInfectedAxes.InnerPosition(3) = app.reAxes.InnerPosition(3);
            app.deceasedAxes.InnerPosition(1) = app.reAxes.InnerPosition(1);
            app.deceasedAxes.InnerPosition(3) = app.reAxes.InnerPosition(3);
            app.rOnsetSlider.Position(1) = app.reAxes.InnerPosition(1);
            app.rOnsetSlider.Position(3) = app.reAxes.InnerPosition(3);
            app.rStartSlider.Position(2) = app.reAxes.InnerPosition(2);
            app.rStartSlider.Position(4) = app.reAxes.InnerPosition(4);
            app.rEndSlider.Position(2) = app.reAxes.InnerPosition(2);
            app.rEndSlider.Position(4) = app.reAxes.InnerPosition(4);
            app.xZoomSlider.Position(1) = app.reAxes.InnerPosition(1);
            
            app.newInfectedsStatusLabel.Position(4) = app.newInfectedAxes.InnerPosition(4);
            app.newInfectedsStatusLabel.Position(2) = app.newInfectedAxes.InnerPosition(2);
            app.reStatusLabel.Position(4) = app.reAxes.InnerPosition(4);
            app.reStatusLabel.Position(2) = app.reAxes.InnerPosition(2);
            app.deceasedStatusLabel.Position(4) = app.deceasedAxes.InnerPosition(4);
            app.deceasedStatusLabel.Position(2) = app.deceasedAxes.InnerPosition(2);
            
            app.loadCountryDropDown.Value = 'Load';

            app.lockYAxis.Value = 0;
            app.newInfectedAxes.YLim = [0,inf];
            app.deceasedAxes.YLim = [0,inf];
            
            app.savedModels = {};
            app.countries = struct;
            app.ghandles = struct;
            
            global reModel
            reModel = 'exponential';
            app.mainModel = Model(reModel);
            
            if (~app.rEndEnableMenu.Checked)
                app.mainModel.rEnd = app.mainModel.rStart;
            end
            
            app.mainModel.update();
            
            try
                app.rOnsetSlider.Value = app.mainModel.rOnset;
                app.rOnsetSpinner.Value = app.mainModel.rOnset;
                app.rStartSlider.Value = app.mainModel.rStart;
                app.rStartSpinner.Value = app.mainModel.rStart;
                app.rEndSlider.Value = app.mainModel.rEnd;
                app.rEndSpinner.Value = app.mainModel.rEnd;
                app.n0Slider.Value = log10(app.mainModel.n0);
                app.n0Spinner.Value = app.mainModel.n0;
            catch
            end
            
            app.hideMainModelButton.Value = 0;
            %             app.xZoomSlider.Value = app.mainModel.nDays;
            
            app.ghandles.reLine = plot(app.reAxes, app.mainModel.day, app.mainModel.re, '.-', 'Color', [0.85,0.33,0.10], 'Tag', 'mainModel_re');
            %             app.ghandles.reConfInt = patch(app.reAxes, [1,2,3], [1,2,3], '.-', 'FaceColor', [0.85,0.33,0.10], 'EdgeColor', 'none', 'FaceAlpha', 0.2, 'Tag', 'mainModel_reConfInt', 'Visible', 'off');
            app.ghandles.reMarker1 = xline(app.reAxes, app.mainModel.rOnset, ':r', 'Tag', 'onsetMarker');
            app.ghandles.reMarker2 = xline(app.reAxes, app.mainModel.zeroCross, ':k', 'Tag', 'mainModel_zcMarker');
            app.ghandles.oneMarker = yline(app.reAxes, 1, '--k', 'Tag', 'oneLine');
            
            app.ghandles.newInfectedsLine = plot(app.newInfectedAxes, app.mainModel.day, app.mainModel.infecteds, '.-', 'Tag', 'mainModel_newInfected');
            hold([app.reAxes app.newInfectedAxes app.deceasedAxes],"on")
            app.ghandles.simulationBars = bar(app.newInfectedAxes, app.mainModel.day, zeros(size(app.mainModel.day)),'FaceAlpha', 0.5, 'Visible','off');
            app.ghandles.newInfectedsMarker1 = xline(app.newInfectedAxes,app.mainModel.rOnset, ':r', 'Tag', 'onsetMarker');
            app.ghandles.newInfectedsMarker2 = xline(app.newInfectedAxes, app.mainModel.zeroCross, ':k', 'Tag', 'mainModel_zcMarker');
            %             app.ghandles.newInfectedsStatus = text(app.newInfectedAxes, 0.9, 0.9, 'status:', "Units", "normalized", "HorizontalAlignment", "right");
            hold([app.reAxes app.newInfectedAxes app.deceasedAxes],"off")
            
            app.ghandles.deceasedLine = plot(app.deceasedAxes, app.mainModel.day, app.mainModel.deceased, '.-k', 'Tag', 'mainModel_deceased');
            %             app.ghandles.deceasedConfInt = patch(app.deceasedAxes, [1,2,3], [1,2,3], '.-', 'FaceColor', [0.00,0.45,0.74], 'EdgeColor', 'none', 'FaceAlpha', 0.2, 'Tag', 'mainModel_deceasedConfInt', 'Visible', 'off');
            hold([app.reAxes app.newInfectedAxes app.deceasedAxes],"on")
            app.ghandles.countryDataLine = plot(app.deceasedAxes, app.mainModel.day, zeros(size(app.mainModel.day)), 'Visible','off');
            app.ghandles.reMarker1 = xline(app.deceasedAxes,app.mainModel.rOnset, ':r', 'Tag', 'onsetMarker');
            app.ghandles.reMarker2 = xline(app.deceasedAxes, app.mainModel.zeroCross, ':k', 'Tag', 'mainModel_zcMarker');
            %             app.ghandles.deceasedStatus = text(app.deceasedAxes, 0.9, 0.9, 'status:', "Units", "normalized", "HorizontalAlignment", "right");
            hold([app.reAxes app.newInfectedAxes app.deceasedAxes],"off")
            
            app.updateModel();
        end

        % Value changing function: rOnsetSlider
        function rOnsetSliderValueChanging(app, event)
            app.mainModel.rOnset = event.Value;
            app.rOnsetSpinner.Value = event.Value;
            app.mainModel.update();
            app.updateModel();
            if (isfield(app.countries, app.loadCountryDropDown.Value))
                app.timeMarkerLabel.Text = sprintf('%s, %s', datestr(app.countries.(app.loadCountryDropDown.Value).dates(round(event.Value)+30)), num2str(round(round(event.Value))));
            else
                app.timeMarkerLabel.Text = num2str(round(event.Value));
            end
            
        end

        % Value changing function: rOnsetSpinner
        function rOnsetSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.rOnsetSlider.Value = changingValue;
            app.mainModel.rOnset = changingValue;
            app.mainModel.update();
            app.updateModel();
        end

        % Value changing function: rEndSlider
        function rEndSliderValueChanging(app, event)
            app.mainModel.rEnd = event.Value;
            app.rEndSpinner.Value = event.Value;
            app.mainModel.update();
            app.updateModel();
            
        end

        % Value changing function: rEndSpinner
        function rEndSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.rEndSlider.Value = changingValue;
            app.mainModel.rEnd = changingValue;
            app.mainModel.update();
            app.updateModel();
            
        end

        % Menu selected function: rEndEnableMenu
        function rEndEnableMenuSelected(app, event)
            app.reAxes.YLimMode
            app.reAxes.XLimMode
            newValue = ~app.rEndEnableMenu.Checked;
            app.rEndEnableMenu.Checked = newValue;
            app.rEndSlider.Enable = newValue;
            app.rEndSpinner.Enable = newValue;
            app.rEndLabel.Enable = newValue;
            app.rSlopeSpinner.Enable = newValue;
            app.rSlopeLabel.Enable = newValue;
            app.mainModel.rEnd = app.iff(~newValue, app.rStartSlider.Value, app.rEndSlider.Value);
            app.rOnsetSlider.Value = app.iff(~newValue, 0, app.mainModel.rOnset);
            app.mainModel.update();
            app.updateModel();
            
        end

        % Value changing function: rStartSlider
        function rStartSliderValueChanging(app, event)
            app.mainModel.rStart = event.Value;
            app.rStartSpinner.Value = event.Value;
            if (~app.rEndEnableMenu.Checked)
                app.mainModel.rEnd = event.Value;
            end
            app.mainModel.update();
            app.updateModel();
            
        end

        % Value changing function: rStartSpinner
        function rStartSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.mainModel.rStart = changingValue;
            app.rStartSlider.Value = changingValue;
            if (~app.rEndEnableMenu.Checked)
                app.mainModel.rEnd = changingValue;
            end
            app.mainModel.update();
            app.updateModel();
        end

        % Value changing function: n0Slider
        function n0SliderValueChanging(app, event)
            app.mainModel.n0 = 10^(event.Value);
            app.n0Spinner.Value = 10^(event.Value);
            app.mainModel.update();
            app.updateModel();
            
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            clc
            runStartupFcn(app, @startupFcn);
        end

        % Button pushed function: fitButton
        function fitButtonPushed(app, event)
            app.mainModel.name = app.loadCountryDropDown.Value;
            app.mainModel.fit(movmean(app.ghandles.countryDataLine.YData,1))
            app.getModel();
            app.updateModel()
            app.saveModel(app.loadCountryDropDown.Value);
        end

        % Button pushed function: simButtonOld
        function simButtonOldPushed(app, event)
            clc
            app.ghandles.simulationBars.Visible = 'on';
            app.mainModel.simulate(app.simGenerations.Value, @app.simulationUpdate);
        end

        % Button pushed function: saveModelButton
        function saveModelButtonPushed(app, event)
            app.saveModel('noname')
        end

        % Button pushed function: clearSavedModelsButton
        function clearSavedModelsButtonPushed(app, event)
            app.savedModels = {};
            delete(findobj(app.UIFigure,'-regexp','Tag','savedModel_*'));
            app.ghandles.countryDataLine.Visible = 'off';
            app.updateModel();
        end

        % Value changed function: loadCountryDropDown
        function loadCountryDropDownValueChanged(app, event)
            value = app.loadCountryDropDown.Value;
            if strcmp(value,'Load')
                return
            end
            country = Country(value,0);
            app.countries.(country.name) = country;
            countryData = movmean(1000000*country.deaths(30:30+app.mainModel.nDays-1)/country.population, 1);
%             countryData = movmean(country.deaths(30:30+app.mainModel.nDays-1), 1);
            
            try
                app.mainModel = app.savedModels.(value).copy();
                app.getModel();
                app.updateModel();
            catch
                disp('no model found');
            end
            
            app.ghandles.countryDataLine.YData = countryData;
            app.ghandles.countryDataLine.Visible = 'on';
            
        end

        % Value changed function: hideMainModelButton
        function hideMainModelButtonValueChanged(app, event)
            value = app.hideMainModelButton.Value;
            set(findobj(app.UIFigure, '-regexp', 'Tag', 'mainModel_*'),'Visible',~value)
            set(findobj(app.UIFigure, 'Tag', 'onsetMarker'),'Color', app.iff(value, 'k','r'))
            
        end

        % Value changing function: xZoomSlider
        function xZoomSliderValueChanging(app, event)
            changingValue = event.Value;
            app.timeZoom(changingValue);
        end

        % Value changing function: n0Spinner
        function n0SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.n0Slider.Value = log10(changingValue);
            app.mainModel.n0 = changingValue;
            app.mainModel.update();
            app.updateModel();
            
        end

        % Value changing function: rSlopeSpinner
        function rSlopeSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.mainModel.rSlope = changingValue;
            app.mainModel.update();
            app.updateModel();
        end

        % Context menu opening function: rSlopeContextMenu
        function rSlopeContextMenuOpening(app, event)
            delete(app.rSlopeUseModelMenu.Children)
            
            if length(app.savedModels) == 0
                return
            end
            
            for name = fieldnames(app.savedModels)'
                
                m = uimenu(app.rSlopeUseModelMenu,'Text',name{1});
                m.MenuSelectedFcn = @modelValueSelect;
            end
            
            function results = modelValueSelect(src, event)
                app.rSlopeSpinner.Value = app.savedModels.(src.Text).rSlope;
                app.mainModel.rSlope = app.savedModels.(src.Text).rSlope;
                app.mainModel.update();
                app.updateModel();
            end
            
        end

        % Menu selected function: rStartLockMenu
        function rStartLockMenuSelected(app, event)
            newValue = ~app.rStartLockMenu.Checked;
            app.rStartLockMenu.Checked = newValue;
            app.rStartSpinner.FontColor = app.iff(newValue, 'r', 'k');
            app.mainModel.rStartLock = newValue;
            
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            oldZoom = app.xZoomSlider.Value;
            newZoom = oldZoom + 10*verticalScrollCount;
            try
                app.xZoomSlider.Value = newZoom;
                app.timeZoom(newZoom);
            catch
            end
            
        end

        % Value changed function: lockYAxis
        function lockYAxisValueChanged(app, event)
            value = app.lockYAxis.Value;
            newValue = value;
            app.newInfectedAxes.YLim = app.iff(~newValue, [0,app.newInfectedAxes.YLim(2)], [0,inf]);
            app.deceasedAxes.YLim = app.iff(~newValue, [0,app.deceasedAxes.YLim(2)], [0,inf]);
            app.deceasedAxes.YLim
            app.deceasedAxes.YLimMode
        end

        % Context menu opening function: rEndContextMenu
        function rEndContextMenuOpening(app, event)
            delete(app.rEndUseModelMenu.Children)
            
            if length(app.savedModels) == 0
                return
            end
            
            for name = fieldnames(app.savedModels)'
                m = uimenu(app.rEndUseModelMenu,'Text',name{1});
                m.MenuSelectedFcn = @modelValueSelect;
            end
            
            function results = modelValueSelect(src, event)
                app.rEndSpinner.Value = app.savedModels.(src.Text).rEnd;
                app.mainModel.rEnd = app.savedModels.(src.Text).rEnd;
                app.mainModel.update();
                app.updateModel();
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.newInfectedAxes = uiaxes(app.UIFigure);
            ylabel(app.newInfectedAxes, 'New Cases / 1 milj')
            app.newInfectedAxes.XLim = [0 200];
            app.newInfectedAxes.YLim = [0 Inf];
            app.reAxes = uiaxes(app.UIFigure);
            ylabel(app.reAxes, 'Re')
            app.reAxes.XLim = [0 200];
            app.reAxes.YLim = [0 3.5];
            app.deceasedAxes = uiaxes(app.UIFigure);
            ylabel(app.deceasedAxes, 'Deceased / 1 milj')
            app.deceasedAxes.XLim = [0 200];
            app.deceasedAxes.YLim = [0 Inf];
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'Reset';
            app.fitButton = uibutton(app.UIFigure, 'push');
            app.fitButton.ButtonPushedFcn = createCallbackFcn(app, @fitButtonPushed, true);
            app.fitButton.Text = 'Fit';
            app.rOnsetSlider = uislider(app.UIFigure);
            app.rOnsetSlider.Limits = [0 200];
            app.rOnsetSlider.MajorTicks = [];
            app.rOnsetSlider.ValueChangingFcn = createCallbackFcn(app, @rOnsetSliderValueChanging, true);
            app.rOnsetSlider.MinorTicks = [];
            app.rOnsetSlider.Value = 20;
            app.rStartSlider = uislider(app.UIFigure);
            app.rStartSlider.Limits = [0.5 3.5];
            app.rStartSlider.MajorTicks = [];
            app.rStartSlider.ValueChangingFcn = createCallbackFcn(app, @rStartSliderValueChanging, true);
            app.rStartSlider.MinorTicks = [];
            app.rStartSlider.Value = 3;
            app.rEndSlider = uislider(app.UIFigure);
            app.rEndSlider.Limits = [0.5 3.5];
            app.rEndSlider.MajorTicks = [];
            app.rEndSlider.ValueChangingFcn = createCallbackFcn(app, @rEndSliderValueChanging, true);
            app.rEndSlider.MinorTicks = [];
            app.rEndSlider.Value = 0.7;
            app.simButtonOld = uibutton(app.UIFigure, 'push');
            app.simButtonOld.ButtonPushedFcn = createCallbackFcn(app, @simButtonOldPushed, true);
            app.simButtonOld.Text = 'Simulate';
            app.n0SliderLabel = uilabel(app.UIFigure);
            app.n0SliderLabel.Text = 'n0';
            app.n0Slider = uislider(app.UIFigure);
            app.n0Slider.Limits = [-3 3];
            app.n0Slider.MajorTicks = [-3 0 3];
            app.n0Slider.MajorTickLabels = {'0.001', '1', '1000'};
            app.n0Slider.ValueChangingFcn = createCallbackFcn(app, @n0SliderValueChanging, true);
            app.n0Slider.MinorTicks = [];
            app.saveModelButton = uibutton(app.UIFigure, 'push');
            app.saveModelButton.ButtonPushedFcn = createCallbackFcn(app, @saveModelButtonPushed, true);
            app.saveModelButton.Text = 'Save';
            app.loadCountryDropDown = uidropdown(app.UIFigure);
            app.loadCountryDropDown.Items = {'Load Data', 'Belgium', 'Denmark', 'Norway', 'Finland', 'Sweden', 'Spain', 'Italy', 'France', 'Netherlands'};
            app.loadCountryDropDown.ItemsData = {'Load', 'Belgium', 'Denmark', 'Norway', 'Finland', 'Sweden', 'Spain', 'Italy', 'France', 'Netherlands'};
            app.loadCountryDropDown.ValueChangedFcn = createCallbackFcn(app, @loadCountryDropDownValueChanged, true);
            app.loadCountryDropDown.Value = 'Load';
            app.hideMainModelButton = uibutton(app.UIFigure, 'state');
            app.hideMainModelButton.ValueChangedFcn = createCallbackFcn(app, @hideMainModelButtonValueChanged, true);
            app.hideMainModelButton.Text = 'Hide Main';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.xZoomSlider = uislider(app.UIFigure);
            app.xZoomSlider.Limits = [5 200];
            app.xZoomSlider.MajorTicks = [];
            app.xZoomSlider.ValueChangingFcn = createCallbackFcn(app, @xZoomSliderValueChanging, true);
            app.xZoomSlider.MinorTicks = [];
            app.xZoomSlider.Value = 200;
            app.rStartSpinner = uispinner(app.UIFigure);
            app.rStartSpinner.Step = 0.1;
            app.rStartSpinner.ValueChangingFcn = createCallbackFcn(app, @rStartSpinnerValueChanging, true);
            app.rStartSpinner.Limits = [0 Inf];
            app.rStartSpinner.ValueDisplayFormat = '%.2f';
            app.rStartSpinner.Value = 3;
            app.rEndSpinner = uispinner(app.UIFigure);
            app.rEndSpinner.Step = 0.05;
            app.rEndSpinner.ValueChangingFcn = createCallbackFcn(app, @rEndSpinnerValueChanging, true);
            app.rEndSpinner.Limits = [0 Inf];
            app.rEndSpinner.ValueDisplayFormat = '%.2f';
            app.rEndSpinner.Value = 0.7;
            app.rOnsetSpinner = uispinner(app.UIFigure);
            app.rOnsetSpinner.ValueChangingFcn = createCallbackFcn(app, @rOnsetSpinnerValueChanging, true);
            app.rOnsetSpinner.Limits = [0 Inf];
            app.rOnsetSpinner.ValueDisplayFormat = '%.1f';
            app.rOnsetSpinner.Value = 20;
            app.simGenerations = uispinner(app.UIFigure);
            app.simGenerations.Value = 20;
            app.n0Spinner = uispinner(app.UIFigure);
            app.n0Spinner.Step = 0.1;
            app.n0Spinner.ValueChangingFcn = createCallbackFcn(app, @n0SpinnerValueChanging, true);
            app.n0Spinner.Limits = [0 Inf];
            app.n0Spinner.ValueDisplayFormat = '%.2f';
            app.n0Spinner.Value = 1;
            app.simButton = uibutton(app.UIFigure, 'state');
            app.simButton.Text = 'Button';
            app.rSlopeSpinner = uispinner(app.UIFigure);
            app.rSlopeSpinner.Step = 0.05;
            app.rSlopeSpinner.ValueChangingFcn = createCallbackFcn(app, @rSlopeSpinnerValueChanging, true);
            app.rSlopeSpinner.Limits = [0 Inf];
            app.rSlopeSpinner.ValueDisplayFormat = '%.2f';
            app.rSlopeSpinner.Value = 0.5;
            app.rStartLabel = uilabel(app.UIFigure);
            app.rStartLabel.Text = 'R0';
            app.rEndLabel = uilabel(app.UIFigure);
            app.rEndLabel.Text = 'R final';
            app.rOnsetLabel = uilabel(app.UIFigure);
            app.rOnsetLabel.Text = 'R suppression day';
            app.rSlopeLabel = uilabel(app.UIFigure);
            app.rSlopeLabel.Text = 'R slope';
            app.timeMarkerLabel = uilabel(app.UIFigure);
            app.timeMarkerLabel.Text = '';
            app.newInfectedsStatusLabel = uilabel(app.UIFigure);
            app.newInfectedsStatusLabel.Text = 'stat';
            app.reStatusLabel = uilabel(app.UIFigure);
            app.reStatusLabel.Text = '';
            app.deceasedStatusLabel = uilabel(app.UIFigure);
            app.deceasedStatusLabel.Text = 'stat';
            app.lockYAxis = uibutton(app.UIFigure, 'state');
            app.lockYAxis.ValueChangedFcn = createCallbackFcn(app, @lockYAxisValueChanged, true);
            app.lockYAxis.Text = 'Lock Y axis';
            app.clearSavedModelsButton = uibutton(app.UIFigure, 'push');
            app.clearSavedModelsButton.ButtonPushedFcn = createCallbackFcn(app, @clearSavedModelsButtonPushed, true);
            app.clearSavedModelsButton.Text = 'Clear';
            app.rStartContextMenu = uicontextmenu(app.UIFigure);
            app.rStartLockMenu = uimenu(app.rStartContextMenu);
            app.rStartLockMenu.MenuSelectedFcn = createCallbackFcn(app, @rStartLockMenuSelected, true);
            app.rStartLockMenu.Text = 'lock';
            % Assign app.rStartContextMenu
            app.rStartSpinner.ContextMenu = app.rStartContextMenu;
            app.rEndContextMenu = uicontextmenu(app.UIFigure);
            app.rEndContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @rEndContextMenuOpening, true);
            app.rEndEnableMenu = uimenu(app.rEndContextMenu);
            app.rEndEnableMenu.MenuSelectedFcn = createCallbackFcn(app, @rEndEnableMenuSelected, true);
            app.rEndEnableMenu.Checked = 'on';
            app.rEndEnableMenu.Text = 'enable';
            app.rEndUseModelMenu = uimenu(app.rEndContextMenu);
            app.rEndUseModelMenu.Text = 'use model';
            % Assign app.rEndContextMenu
            app.rEndSpinner.ContextMenu = app.rEndContextMenu;
            app.n0Spinner.ContextMenu = app.rEndContextMenu;
            app.rOnsetContextMenu = uicontextmenu(app.UIFigure);
            app.rOnsetLockMenu = uimenu(app.rOnsetContextMenu);
            app.rOnsetLockMenu.Checked = 'on';
            app.rOnsetLockMenu.Text = 'lock';
            % Assign app.rOnsetContextMenu
            app.rOnsetSpinner.ContextMenu = app.rOnsetContextMenu;
            app.rSlopeContextMenu = uicontextmenu(app.UIFigure);
            app.rSlopeContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @rSlopeContextMenuOpening, true);
            app.rSlopeUseModelMenu = uimenu(app.rSlopeContextMenu);
            app.rSlopeUseModelMenu.Text = 'use model';
            % Assign app.rSlopeContextMenu
            app.rSlopeSpinner.ContextMenu = app.rSlopeContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end