classdef ModelApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ResetButton          matlab.ui.control.Button
        fitButton            matlab.ui.control.Button
        SliderLabel          matlab.ui.control.Label
        rOnsetSlider         matlab.ui.control.Slider
        rStartSlider         matlab.ui.control.Slider
        rEndSlider           matlab.ui.control.Slider
        simulateButton       matlab.ui.control.Button
        n0SliderLabel        matlab.ui.control.Label
        n0Slider             matlab.ui.control.Slider
        saveModelButton      matlab.ui.control.Button
        loadCountryDropDown  matlab.ui.control.DropDown
        hideMainModelButton  matlab.ui.control.StateButton
        Label                matlab.ui.control.Label
        xZoomSlider          matlab.ui.control.Slider
        rStartSpinner        matlab.ui.control.Spinner
        rEndSpinner          matlab.ui.control.Spinner
        rOnsetSpinner        matlab.ui.control.Spinner
        newInfectedAxes      matlab.ui.control.UIAxes
        reAxes               matlab.ui.control.UIAxes
        deceasedAxes         matlab.ui.control.UIAxes
        rStartContextMenu    matlab.ui.container.ContextMenu
        rStartLockMenu       matlab.ui.container.Menu
        rEndContextMenu      matlab.ui.container.ContextMenu
        rEndEnableMenu       matlab.ui.container.Menu
        rOnsetContextMenu    matlab.ui.container.ContextMenu
        rOnsetLockMenu       matlab.ui.container.Menu
    end

    
    properties (Access = private)
        newInfectedsLine% Description
        reLine % Description
        deceasedLine % Description
        mainModel % Description
        simulationBars % Description
        savedModels % Description
        countries % Description
        countryDataLine % Description
        ghandles % Description
    end
    
    methods (Access = private)
        
        function updateModel(app)
            app.reAxes.PositionConstraint = 'innerposition';
            app.newInfectedAxes.PositionConstraint = 'innerposition';
            app.deceasedAxes.PositionConstraint = 'innerposition';
            
            app.ghandles.reLine.YData = app.mainModel.re;
            app.ghandles.newInfectedsLine.YData = app.mainModel.newInfecteds;
%             app.ghandles.newInfectedsLine.YData = app.mainModel.infecteds;
            app.ghandles.deceasedLine.YData = app.mainModel.deceased;
            
             set(findobj(app.UIFigure, 'Tag', 'onsetMarker'), 'Value', app.mainModel.rOnset);
             set(findobj(app.UIFigure, 'Tag', 'zcMarker'), 'Value', app.mainModel.zeroCross);
            
        end
        
        function simulationUpdate(app, message, infectedHist, deceasedHist)
            disp(message);
            app.ghandles.simulationBars.YData = infectedHist;
            drawnow;
        end
        
        function setModel(app)
            app.mainModel.rOnset = app.rOnsetSlider.Value;
            app.mainModel.rStart = app.rStart.Value;
            app.mainModel.rEnd = app.rEndSlider.Value;
            app.mainModel.n0 = app.n0Slider.Value;
        end
        
        function results = getModel(app)
            try
                app.rOnsetSlider.Value = app.mainModel.rOnset;
                app.rOnsetSpinner.Value = app.mainModel.rOnset;
                app.rStartSlider.Value = app.mainModel.rStart;
                app.rStartSpinner.Value = app.mainModel.rStart;
                app.rEndSlider.Value = app.mainModel.rEnd;
                app.rEndSpinner.Value = app.mainModel.rEnd;
                app.n0Slider.Value = log10(app.mainModel.n0);
    %             app.n0Spinner.Value = app.mainModel.n0;
            catch
                disp('OUT OF BOUNDS')
            end
        end
        
        function results = iff(app, condition, a, b)
            results = b;
            if condition
                results = a;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.newInfectedAxes.InnerPosition(1) = app.reAxes.InnerPosition(1);
            app.newInfectedAxes.InnerPosition(3) = app.reAxes.InnerPosition(3);
            app.deceasedAxes.InnerPosition(1) = app.reAxes.InnerPosition(1);
            app.deceasedAxes.InnerPosition(3) = app.reAxes.InnerPosition(3);
            app.rOnsetSlider.Position(1) = app.reAxes.InnerPosition(1);
            app.rOnsetSlider.Position(3) = app.reAxes.InnerPosition(3);

            app.savedModels = [];
            app.countries = struct;
            app.ghandles = struct;
            
            global reModel
            reModel = 'exponential';
            app.mainModel = Model(reModel);
%             app.mainModel.n0 = 200;
%             app.mainModel.rOnset = app.rOnsetSlider.Value;
%             app.mainModel.rEnd = app.rEndSlider.Value;
%             app.mainModel.rStart = app.rStartSlider.Value;
            app.mainModel.update();
            
            app.rOnsetSlider.Value = app.mainModel.rOnset;
            app.rStartSlider.Value = app.mainModel.rStart;
            app.rEndSlider.Value = app.mainModel.rEnd;
            app.n0Slider.Value = log10(app.mainModel.n0);
            
            app.ghandles.reLine = plot(app.reAxes, app.mainModel.day, app.mainModel.re, '.-', 'Color', [0.85,0.33,0.10], 'Tag', 'mainModel_re');
            app.ghandles.reConfInt = patch(app.reAxes, [1,2,3], [1,2,3], '.-', 'FaceColor', [0.85,0.33,0.10], 'EdgeColor', 'none', 'FaceAlpha', 0.2, 'Tag', 'mainModel_cinfInt', 'Visible', 'off');
            app.ghandles.reMarker1 = xline(app.reAxes, app.mainModel.rOnset, ':r', 'Tag', 'onsetMarker');
            app.ghandles.reMarker2 = xline(app.reAxes, app.mainModel.zeroCross, ':k', 'Tag', 'zcMarker');
            app.ghandles.oneMarker = yline(app.reAxes, 1, '--k', 'Tag', 'oneLine');

            app.ghandles.newInfectedsLine = plot(app.newInfectedAxes, app.mainModel.day, app.mainModel.infecteds, '.-', 'Tag', 'mainModel_newInfected');
            hold([app.reAxes app.newInfectedAxes app.deceasedAxes],"on")
            app.ghandles.simulationBars = bar(app.newInfectedAxes, app.mainModel.day, zeros(size(app.mainModel.day)), 'Visible','off');
            app.ghandles.reMarker1 = xline(app.newInfectedAxes,app.mainModel.rOnset, ':r', 'Tag', 'onsetMarker');
            app.ghandles.reMarker2 = xline(app.newInfectedAxes, app.mainModel.zeroCross, ':k', 'Tag', 'zcMarker');
            hold([app.reAxes app.newInfectedAxes app.deceasedAxes],"off")

            app.ghandles.deceasedLine = plot(app.deceasedAxes, app.mainModel.day, app.mainModel.deceased, '.-', 'Tag', 'mainModel_deceased');
            hold([app.reAxes app.newInfectedAxes app.deceasedAxes],"on")
            app.ghandles.countryDataLine = plot(app.deceasedAxes, app.mainModel.day, zeros(size(app.mainModel.day)), 'Visible','off');
            app.ghandles.reMarker1 = xline(app.deceasedAxes,app.mainModel.rOnset, ':r', 'Tag', 'onsetMarker');
            app.ghandles.reMarker2 = xline(app.deceasedAxes, app.mainModel.zeroCross, ':k', 'Tag', 'zcMarker');
            hold([app.reAxes app.newInfectedAxes app.deceasedAxes],"off")

            app.updateModel();
        end

        % Value changing function: rOnsetSlider
        function rOnsetSliderValueChanging(app, event)
            app.mainModel.rOnset = event.Value;
            app.rOnsetSpinner.Value = event.Value;
            app.mainModel.update();
            app.updateModel();
        end

        % Value changing function: rEndSlider
        function rEndSliderValueChanging(app, event)
            app.mainModel.rEnd = event.Value;
            app.rEndSpinner.Value = event.Value;
            app.mainModel.update();
            app.updateModel();
            
        end

        % Value changing function: rStartSlider
        function rStartSliderValueChanging(app, event)
            app.mainModel.rStart = event.Value;
            app.rStartSpinner.Value = event.Value;
            if (~app.rEndEnableMenu.Checked)
                app.mainModel.rEnd = event.Value;
            end
            app.mainModel.update();
            app.updateModel();
            
        end

        % Value changing function: n0Slider
        function n0SliderValueChanging(app, event)
            app.mainModel.n0 = 10^(event.Value);
            app.mainModel.update();
            app.updateModel();
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            clc
            runStartupFcn(app, @startupFcn);
        end

        % Button pushed function: fitButton
        function fitButtonPushed(app, event)
            app.mainModel.fit(movmean(app.ghandles.countryDataLine.YData,1))
            app.getModel();
            app.updateModel()
            confPatch = findobj(app.reAxes.Children,'Tag','mainModel_cinfInt');
            confPatch.Visible = 'on';
            confPatch.XData = [app.mainModel.day, fliplr(app.mainModel.day)];
            confPatch.YData = [app.mainModel.reUpper', fliplr(app.mainModel.reLower')];

        end

        % Button pushed function: simulateButton
        function simulateButtonPushed(app, event)
            clc
            app.ghandles.simulationBars.Visible = 'on';
            app.mainModel.simulate(40, @app.simulationUpdate);
        end

        % Button pushed function: saveModelButton
        function saveModelButtonPushed(app, event)
%             app.savedModels = [app.savedModels, app.mainModel.copy()]; 
            
            colors = get(app.reAxes,'colororder');
            hold([app.reAxes app.newInfectedAxes app.deceasedAxes],"on")
            plot(app.reAxes,app.mainModel.day,app.mainModel.re,'-','Color', colors(2,:), 'Tag','savedModelLine');
            plot(app.newInfectedAxes,app.mainModel.day,app.mainModel.newInfecteds, '-', 'Color', colors(1,:),'Tag','savedModelLine');
            plot(app.deceasedAxes,app.mainModel.day,app.mainModel.deceased,'-','Color', colors(1,:) ,'Tag','savedModelLine');
            app.mainModel.fitData
            if (~isempty(app.mainModel.fitData))
                plot(app.deceasedAxes,app.mainModel.day,app.mainModel.fitData,'-', 'Color', colors(1,:),'Tag','savedModelLine');
            end
            hold([app.reAxes app.newInfectedAxes app.deceasedAxes],"off")

            app.updateModel();
        end

        % Value changed function: loadCountryDropDown
        function loadCountryDropDownValueChanged(app, event)
            value = app.loadCountryDropDown.Value;
            if strcmp(value,'Load')
                return
            end
            country = Country(value,0);
            %             app.country = country;
            app.countries.(country.name) = country;
            
            countryData = movmean(1000000*country.deaths(30:30+app.mainModel.nDays-1)/country.population, 1);

            app.ghandles.countryDataLine.YData = countryData;
            app.ghandles.countryDataLine.Visible = 'on';

            %             hold(app.deceasedAxes,"on")
%             plot(app.deceasedAxes, app.mainModel.day, countryData,'-','Tag','countryLine');
%             hold(app.deceasedAxes,"off")
            
%             app.mainModel.

        end

        % Value changed function: hideMainModelButton
        function hideMainModelButtonValueChanged(app, event)
            value = app.hideMainModelButton.Value;
            set(findobj(app.UIFigure, '-regexp', 'Tag', 'mainModel_*'),'Visible',~value)
        end

        % Value changing function: xZoomSlider
        function xZoomSliderValueChanging(app, event)
            changingValue = event.Value;
            app.newInfectedAxes.YLimMode = 'auto';
            app.deceasedAxes.YLimMode = 'auto';
            app.reAxes.XLim(2) = changingValue;
            app.newInfectedAxes.XLim(2) = changingValue;
            app.deceasedAxes.XLim(2) = changingValue;
            app.reAxes.XTickMode = 'auto';
            app.newInfectedAxes.XTickMode = 'auto';
            app.deceasedAxes.XTickMode = 'auto';
            
        end

        % Menu selected function: rEndEnableMenu
        function rEndEnableMenuSelected(app, event)
            newValue = ~app.rEndEnableMenu.Checked;
            app.rEndEnableMenu.Checked =newValue;
            app.rEndSlider.Enable = newValue;
            app.rEndSpinner.Enable = newValue;
            app.mainModel.rEnd = app.iff(~newValue, app.rStartSlider.Value, app.rEndSlider.Value);
            app.mainModel.update();
            app.updateModel();
        end

        % Value changing function: rStartSpinner
        function rStartSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.rStartSlider.Value = changingValue;
            app.mainModel.rStart = changingValue;
            app.mainModel.update();
            app.updateModel();
        end

        % Value changing function: rEndSpinner
        function rEndSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.rEndSlider.Value = changingValue;
            app.mainModel.rEnd = changingValue;
            app.mainModel.update();
            app.updateModel();

        end

        % Value changing function: rOnsetSpinner
        function rOnsetSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.rOnsetSlider.Value = changingValue;
            app.mainModel.rOnset = changingValue;
            app.mainModel.update();
            app.updateModel();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.fitButton = uibutton(app.UIFigure, 'push');
            app.fitButton.ButtonPushedFcn = createCallbackFcn(app, @fitButtonPushed, true);
            app.fitButton.Text = 'Fit';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.rOnsetSlider = uislider(app.UIFigure);
            app.rOnsetSlider.Limits = [0 200];
            app.rOnsetSlider.MajorTicks = [];
            app.rOnsetSlider.ValueChangingFcn = createCallbackFcn(app, @rOnsetSliderValueChanging, true);
            app.rOnsetSlider.MinorTicks = [];
            app.rOnsetSlider.Value = 20;
            app.rStartSlider = uislider(app.UIFigure);
            app.rStartSlider.Limits = [0 5];
            app.rStartSlider.MajorTicks = [];
            app.rStartSlider.ValueChangingFcn = createCallbackFcn(app, @rStartSliderValueChanging, true);
            app.rStartSlider.MinorTicks = [];
            app.rStartSlider.Value = 3;
            app.rEndSlider = uislider(app.UIFigure);
            app.rEndSlider.Limits = [0 5];
            app.rEndSlider.MajorTicks = [];
            app.rEndSlider.ValueChangingFcn = createCallbackFcn(app, @rEndSliderValueChanging, true);
            app.rEndSlider.MinorTicks = [];
            app.rEndSlider.Value = 0.7;
            app.simulateButton = uibutton(app.UIFigure, 'push');
            app.simulateButton.ButtonPushedFcn = createCallbackFcn(app, @simulateButtonPushed, true);
            app.simulateButton.Text = 'Simulate';
            app.n0SliderLabel = uilabel(app.UIFigure);
            app.n0SliderLabel.Text = 'n0';
            app.n0Slider = uislider(app.UIFigure);
            app.n0Slider.Limits = [-3 3];
            app.n0Slider.MajorTicks = [-3 0 3];
            app.n0Slider.MajorTickLabels = {'0.001', '1', '1000'};
            app.n0Slider.ValueChangingFcn = createCallbackFcn(app, @n0SliderValueChanging, true);
            app.n0Slider.MinorTicks = [];
            app.saveModelButton = uibutton(app.UIFigure, 'push');
            app.saveModelButton.ButtonPushedFcn = createCallbackFcn(app, @saveModelButtonPushed, true);
            app.saveModelButton.Text = 'Save';
            app.loadCountryDropDown = uidropdown(app.UIFigure);
            app.loadCountryDropDown.Items = {'Load Data', 'Belgium', 'Denmark', 'Sweden', 'Spain', 'Italy', 'France'};
            app.loadCountryDropDown.ItemsData = {'Load', 'Belgium', 'Denmark', 'Sweden', 'Spain', 'Italy', 'France'};
            app.loadCountryDropDown.ValueChangedFcn = createCallbackFcn(app, @loadCountryDropDownValueChanged, true);
            app.loadCountryDropDown.Value = 'Load';
            app.hideMainModelButton = uibutton(app.UIFigure, 'state');
            app.hideMainModelButton.ValueChangedFcn = createCallbackFcn(app, @hideMainModelButtonValueChanged, true);
            app.hideMainModelButton.Text = 'Hide Main';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.xZoomSlider = uislider(app.UIFigure);
            app.xZoomSlider.Limits = [5 200];
            app.xZoomSlider.MajorTicks = [];
            app.xZoomSlider.ValueChangingFcn = createCallbackFcn(app, @xZoomSliderValueChanging, true);
            app.xZoomSlider.MinorTicks = [];
            app.xZoomSlider.Value = 200;
            app.rStartSpinner = uispinner(app.UIFigure);
            app.rStartSpinner.Step = 0.1;
            app.rStartSpinner.ValueChangingFcn = createCallbackFcn(app, @rStartSpinnerValueChanging, true);
            app.rStartSpinner.Limits = [0 Inf];
            app.rStartSpinner.ValueDisplayFormat = '%.2f';
            app.rStartSpinner.Value = 3;
            app.rEndSpinner = uispinner(app.UIFigure);
            app.rEndSpinner.Step = 0.1;
            app.rEndSpinner.ValueChangingFcn = createCallbackFcn(app, @rEndSpinnerValueChanging, true);
            app.rEndSpinner.Limits = [0 Inf];
            app.rEndSpinner.ValueDisplayFormat = '%.2f';
            app.rEndSpinner.Value = 0.7;
            app.rOnsetSpinner = uispinner(app.UIFigure);
            app.rOnsetSpinner.ValueChangingFcn = createCallbackFcn(app, @rOnsetSpinnerValueChanging, true);
            app.rOnsetSpinner.Limits = [0 Inf];
            app.rOnsetSpinner.ValueDisplayFormat = '%.1f';
            app.rOnsetSpinner.Value = 20;
            app.newInfectedAxes = uiaxes(app.UIFigure);
            app.newInfectedAxes.XLim = [0 200];
            app.reAxes = uiaxes(app.UIFigure);
            app.reAxes.XLim = [0 200];
            app.reAxes.YLim = [0.5 5];
            app.deceasedAxes = uiaxes(app.UIFigure);
            app.deceasedAxes.XLim = [0 200];
            app.rStartContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.rStartContextMenu
            app.rStartSpinner.ContextMenu = app.rStartContextMenu;
            app.rStartLockMenu = uimenu(app.rStartContextMenu);
            app.rStartLockMenu.Checked = 'on';
            app.rStartLockMenu.Text = 'lock';
            app.rEndContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.rEndContextMenu
            app.rEndSpinner.ContextMenu = app.rEndContextMenu;
            app.rEndEnableMenu = uimenu(app.rEndContextMenu);
            app.rEndEnableMenu.MenuSelectedFcn = createCallbackFcn(app, @rEndEnableMenuSelected, true);
            app.rEndEnableMenu.Checked = 'on';
            app.rEndEnableMenu.Text = 'enable';
            app.rOnsetContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.rOnsetContextMenu
            app.rOnsetSpinner.ContextMenu = app.rOnsetContextMenu;
            app.rOnsetLockMenu = uimenu(app.rOnsetContextMenu);
            app.rOnsetLockMenu.Checked = 'on';
            app.rOnsetLockMenu.Text = 'lock';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end