classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SliderLabel                   matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        onsetSlider                   matlab.ui.control.Slider
        LockyscaleCheckBox            matlab.ui.control.CheckBox
        SaveButton                    matlab.ui.control.Button
        RstartSlider                  matlab.ui.control.Slider
        RendSlider                    matlab.ui.control.Slider
        ClearButton                   matlab.ui.control.Button
        n0Slider                      matlab.ui.control.Slider
        LoadDataDropDown              matlab.ui.control.DropDown
        RlockCheckBox                 matlab.ui.control.CheckBox
        RendSpinner                   matlab.ui.control.Spinner
        delayEditFieldLabel           matlab.ui.control.Label
        delayInput                    matlab.ui.control.NumericEditField
        serialintervalEditFieldLabel  matlab.ui.control.Label
        serialintervalEditField       matlab.ui.control.NumericEditField
        IFREditFieldLabel             matlab.ui.control.Label
        IFREditField                  matlab.ui.control.NumericEditField
        varianceEditField_2Label      matlab.ui.control.Label
        varianceInput                 matlab.ui.control.NumericEditField
        fitButton                     matlab.ui.control.Button
        RstartSpinner                 matlab.ui.control.Spinner
        RmodelDropDownLabel           matlab.ui.control.Label
        RmodelDropDown                matlab.ui.control.DropDown
        N0SpinnerLabel                matlab.ui.control.Label
        N0Spinner                     matlab.ui.control.Spinner
        simGenerations                matlab.ui.control.NumericEditField
        SimButton                     matlab.ui.control.StateButton
        slopeLabel                    matlab.ui.control.Label
        supressionSlopeSpinner        matlab.ui.control.Spinner
        RsupressiondaySpinnerLabel    matlab.ui.control.Label
        supressionDateSpinner         matlab.ui.control.Spinner
        DateLabel                     matlab.ui.control.Label
        lockRsupCheckBox              matlab.ui.control.CheckBox
        Label                         matlab.ui.control.Label
        xZoomSlider                   matlab.ui.control.Slider
        lockRstartCheckBox            matlab.ui.control.CheckBox
        simStatusTextArea             matlab.ui.control.TextArea
        casesPlot                     matlab.ui.control.UIAxes
        RePlot                        matlab.ui.control.UIAxes
        deathsPlot                    matlab.ui.control.UIAxes
        MainContextMenu               matlab.ui.container.ContextMenu
        resetMenu                     matlab.ui.container.Menu
        togglelinlogMenu              matlab.ui.container.Menu
        togglesimstatusMenu           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        timeShift = 0 % Description
        tau % Description
        Rstart % Description
        onset % Description
        slope % Description
        Ndays % Description
        savedModels % Description
        nCases % Description
        Re % Description
        nDay % Description
        n0 % Description
        nDeaths% Description
        nDeathsDist % Description
        slope2 % Description
        simDataInfections % Description
        simDataDeceased % Description
        countryData % Description
        simNGenerations % Description
        countries % Description
        zeroCross % Description
        simData % Description
        latestSimDataInfections % Description
        casesLine % Description
        latestSimStatusMessage % Description
        savedCountryData % Description
        Rend % Description
    end
    
    methods (Access = private)
        
        
        function results = generateData(app)
            
            app.casesPlot.PositionConstraint = 'innerposition';
            app.RePlot.PositionConstraint = 'innerposition';
            
            maxCases = 0;
            
            totNCasesOld = sum(app.nCases);
            [app.nCases, app.Re] = iModel(app.nDay, app.n0, app.tau, app.Rstart, app.Rend, app.onset, app.slope, app.slope2);
            
            
            %should be new cases and not number of cases
            app.nCases = app.nCases';
            %             app.nCases = (app.Re(1)^(1/app.tau)-1)*app.nCases';
            
            if (max(app.nCases) > maxCases)
                maxCases = max(app.nCases);
            end
            app.zeroCross = app.zeroCrossing(app.Re-1);
            
            
            %             if (isvalid(app.simDataInfections))
            %                 hold(app.casesPlot,'on');
            %             end
            
            %             delete(app.casesLine);
            plot(app.casesPlot, app.nDay, app.nCases, '.-b', 'UserData', 'cases');
            hold(app.casesPlot,'on');
            
            plot(app.RePlot, app.nDay, app.Re, '.-r', 'UserData', 're');
            hold(app.RePlot,'on');
            
            for i = 1:length(app.savedModels)
                data = app.savedModels{i};
                if (max(data(2,:)) > maxCases)
                    maxCases = max(max(data(2,:)));
                end
                %                 plot(app.casesPlot, data(1,:), data(2,:), '.-','Color',[0.5 0.5 0.5]);
                line = plot(app.casesPlot, data(1,:), data(2,:), '-','Color', 'k');
                dots = scatter(app.casesPlot, data(1,:), data(2,:),3,'filled');
                line.Color(4) = 0.5;
                dots.MarkerFaceColor = 'b';
                dots.MarkerEdgeColor = 'b';
                dots.MarkerEdgeAlpha = 0.3;
                dots.MarkerFaceAlpha = 0.3;
                
                %                 plot(app.RePlot, data(1,:), data(4,:), '.-','Color',[0.5 0.5 0.5]);
                line = plot(app.RePlot, data(1,:), data(4,:), '-','Color', 'k');
                dots = scatter(app.RePlot, data(1,:), data(4,:),3,'filled');
                line.Color(4) = 0.5;
                dots.MarkerFaceColor = 'r';
                dots.MarkerEdgeColor = 'r';
                dots.MarkerEdgeAlpha = 0.3;
                dots.MarkerFaceAlpha = 0.3;
            end
            if (~app.LockyscaleCheckBox.Value)
                ylim(app.casesPlot, [0, maxCases*1.2]);
            end
            
            mline1 = xline(app.casesPlot, app.onset, ':k', "LineWidth", 0.1, 'UserData', 'mline1');
            mline2 = xline(app.RePlot, app.onset, ':k',  "LineWidth", 0.1, 'UserData', 'mline1');
            mline3 = xline(app.casesPlot, app.zeroCross+0.5, ':r', "LineWidth", 0.1, 'UserData', 'mline2');
            mline4 = xline(app.RePlot, app.zeroCross, ':r',  "LineWidth", 0.1, 'UserData', 'mline2');
            mline1.Color(4) = 0.5; mline2.Color(4) = 0.5; mline3.Color(4) = 0.5; mline4.Color(4) = 0.5;
            
            plot(app.RePlot, [0,app.Ndays], [1,1], '--r',  "LineWidth", 0.1);
            
            if (isvalid(app.latestSimDataInfections))
                copyobj(app.latestSimDataInfections, [app.casesPlot]);
            end
            
            hold(app.casesPlot,'off');
            hold(app.RePlot,'off');
            
            infectionRatio = '-';
            if (isfield(app.countries, app.LoadDataDropDown.Value))
                infectionRatio = sprintf('%.0f',100*sum(app.nCases)/app.countries.(app.LoadDataDropDown.Value).population);
            end
            
            infectedStatusText =  sprintf(...
                ['             n0: %-g\n',...
                '       infected: %-.0f\n',...
                '         change: %.2f%%\n',...
                '%% of population: %s\n',...
                '     sim status: %s'], ...
                app.n0, sum(app.nCases), 100*(sum(app.nCases)-totNCasesOld)/totNCasesOld, infectionRatio, app.latestSimStatusMessage);
            
            text(app.casesPlot, 0.02,0.95,'New Infections','Units','normalized','Color','b');
            text(app.casesPlot, 0.5 ,0.80, infectedStatusText,'Units','normalized','Color','b','FontName','Lucida Console');
            text(app.RePlot,0.02,0.95,'Re (effective Reproduction number)','Units','normalized','Color','r');
            
        end
        
        function results = simulateDeaths(app, resimulate)
            
            app.deathsPlot.PositionConstraint = 'innerposition';
            
            maxDeceased = 0;
            
            %
            if (resimulate)
                deathDays = [];
                %deathPdf = poisspdf(0:39,20);
                deathPdf = lognpdf(1:40,log(20),0.2);
                app.nDeathsDist = zeros(1,app.Ndays);
                for nd = app.nDay
                    if (nd < app.Ndays-38)
                        app.nDeathsDist(nd:nd+40-1) = app.nDeathsDist(nd:nd+40-1) + 0.01*app.nCases(nd)*deathPdf;
                    else
                        nLeft = app.Ndays - nd;
                        app.nDeathsDist(nd:nd+nLeft) = app.nDeathsDist(nd:nd+nLeft) + 0.01*app.nCases(nd)*deathPdf(1:nLeft+1);
                    end
                end
            end
            
            if (max(app.nDeathsDist) > maxDeceased)
                maxDeceased = max(app.nDeathsDist);
            end
            
            % plot death data
            plot(app.deathsPlot, app.nDay, app.nDeathsDist, '.-k')
            hold(app.deathsPlot,"on");
            
            for i = 1:length(app.savedModels)
                data = app.savedModels{i};
                line = plot(app.deathsPlot, data(1,:), data(3,:), '-','Color', [0 0 0]);
                dots = scatter(app.deathsPlot, data(1,:), data(3,:),3,'filled');
                line.Color(4) = 0.5;
                dots.MarkerFaceColor = 'k';
                dots.MarkerEdgeColor = 'k';
                dots.MarkerEdgeAlpha = 0.5;
                dots.MarkerFaceAlpha = 0.5;
                if (max(data(3,:)) > maxDeceased)
                    maxDeceased = max(data(3,:));
                end
            end
            
            if (isfield(app.countries, app.LoadDataDropDown.Value))
                %             if (strcmp(class(app.country),'Country'))
                ts = floor(app.timeShift);
                %range = app.country.firstCaseDay:app.country.firstCaseDay+app.Ndays-1-ts;
                range = 30:30+app.Ndays-1-ts;
                countryDeceased = [zeros(1,ts), movmean(app.countries.(app.LoadDataDropDown.Value).deaths(range), 1)];
                app.countryData = plot(app.deathsPlot, ...
                    app.nDay, ...
                    countryDeceased, ...
                    '.-');
                %                 plot(app.country.days,movmean(app.country.deaths,14)); hold on
                
                if (max(countryDeceased) > maxDeceased)
                    maxDeceased = max(countryDeceased);
                end
                
            end
            
            ylim(app.deathsPlot,[0 maxDeceased*1.1]);
            
            mline1 = xline(app.deathsPlot, app.onset, ':k',  "LineWidth", 0.1, 'UserData', 'mline1');
            mline2 = xline(app.deathsPlot, app.zeroCross+0.5, ':r',  "LineWidth", 0.1, 'UserData', 'mline2');
            mline1.Color(4) = 0.5; mline2.Color(4) = 0.5;
            
            hold(app.deathsPlot,"off");
            
            text(app.deathsPlot,0.02,0.95,'Deceased','Units','normalized','Color','k');
            
        end
        
        function result = zeroCrossing(app,y)
            try
                zc = find(y(:).*circshift(y(:), [-1 0]) <= 0);
                result = zc(1);
            catch
                result = -1;
            end
        end
        
        function mouseMove(app, objectHandle, event)
            %             app.RePlot.CurrentPoint(1,3)
            %             app.casesPlot.CurrentPoint(1,3)
            %             app.deathsPlot.CurrentPoint(1,3)
            %             app.RePlot.CurrentPoint(1,3) == 1
            if (app.RePlot.CurrentPoint(1,3) == 1 || app.deathsPlot.CurrentPoint(1,3))
                try
                    if (isfield(app.countries, app.LoadDataDropDown.Value))
                        app.DateLabel.Text = sprintf('%s, %s', datestr(app.countries.(app.LoadDataDropDown.Value).dates(floor(app.RePlot.CurrentPoint(1,1))+30)), num2str(round(app.RePlot.CurrentPoint(1,1))));
                    else
                        app.DateLabel.Text = num2str(round(app.RePlot.CurrentPoint(1,1)));
                    end
                catch
                end
            end
        end
        
        %         function results = infectedStatusText(app, simstatusText)
        %         end
        
        function results = update(app, resimulateDeceased)
            app.generateData();
            app.simulateDeaths(resimulateDeceased);
        end
        
        function results = iff(app, condition,a,b)
            results = b;
            if condition
                results = a;
            end
        end
        
        function results = simulateInfections(app)
            N0 = 1;
            nInfecteds = N0;
            infecteds = [];
            infectionDay = [];
            infectionHist = zeros(size(app.nDay));
            deceasedHist = zeros(size(app.nDay));
            nInf = 0;
            nRem = 0;
            
            delete(app.latestSimDataInfections);
            
            hold(app.casesPlot,"on")
            hold(app.deathsPlot,"on")
            
            simStatusMessage = '';
            
            index = 1;
            for i=1:N0
                patientZero = Person(0, app.Rstart, -1, index, app.Re);
                
                
                simStatusMessage = [simStatusMessage, ...
                    sprintf('day 000: person %4i infected by unkn and infects %i others on days ',...
                    index, ...
                    patientZero.nInfections),...
                    sprintf('%g ', patientZero.infectSchedule),...
                    sprintf('\n')];
                
                %                 simStatusMessage = [simStatusMessage sprintf('day 0: person %i infected by X and infects %i others on days %g\n',...
                %                     index, ...
                %                     patientZero.nInfections,...
                %                     patientZero.infectSchedule)];
                %                 %                 disp(app.latestSimStatusMessage);
                
                nInf = nInf +1;
                index = index + 1;
                infecteds = [infecteds patientZero];
                infectionDay = [infectionDay 0];
                
            end
            
            for day = 1:app.simNGenerations
                
                if (~app.SimButton.Value)
                    return
                end
                
                nInfecteds = [nInfecteds length(infecteds(find([infecteds.suceptible] == 1)))];
                
                simStatusText =  ['DAY ' num2str(day) ' infected: ' num2str(length(infecteds(find([infecteds.suceptible] == 1))))];
                
                iInfected = 1;
                for infected = infecteds(find([infecteds.suceptible] == 1))
                    
                    for daysToInfectDay = infected.infectSchedule
                        if day == daysToInfectDay
                            Rmodified = app.Re(day);
                            newInfected = Person(day, Rmodified, infected.index, index, app.Re);
                            
                            simStatusMessage = [simStatusMessage, ...
                                sprintf('day %03i: person %4i infected by %4i and infects %i others on days ',...
                                day, ...
                                index, ...
                                infected.index,...
                                newInfected.nInfections),...
                                sprintf('%g ', newInfected.infectSchedule),...
                                sprintf('\n')
                                ];
                            
                            nInf = nInf +1;
                            index = index + 1;
                            infecteds = [infecteds newInfected];
                            infectionDay = [infectionDay day];
                            infectionHist(day) = infectionHist(day)+1;
                            
                            if (rand<0.01)
                                deceaseDay = day + round(lognrnd(log(20),0.2));
                                deceasedHist(deceaseDay) = deceasedHist(deceaseDay) + 1;
                            end
                        end
                    end
                    
                    if day >= infected.removalDay
                        infected.suceptible = 0;
                        nRem = nRem + 1;
                    end
                    
                    
                    iInfected = iInfected + 1;
                end
                
                app.simStatusTextArea.Value = simStatusMessage;
                
                %                 nInfected = app.nCases(day) + round(sqrt(app.nCases(day)))*randn;
                %                 infectionHist(day) = nInfected;
                %                 for i = 1:nInfected
                %                     if (rand<0.01)
                %                         deceaseDay = day + round(lognrnd(log(19),0.2))
                %                         try
                %                             deceasedHist(deceaseDay) = deceasedHist(deceaseDay) + 1;
                %                         catch
                %                         end
                %                     end
                %                 end
                
                delete(app.simDataInfections)
                delete(app.simDataDeceased)
                app.simDataInfections = bar(app.casesPlot,app.nDay, infectionHist,'FaceColor',[0 0 0],"FaceAlpha",0.3);
                app.simDataDeceased = bar(app.deathsPlot,app.nDay(1:day), deceasedHist(1:day),'EdgeColor','none','FaceColor',[0 0 0],"FaceAlpha",0.3);
                drawnow
                app.latestSimDataInfections = app.simDataInfections.copy();
            end
            
            %             nCases = hist(infectionDay,app.nDay);
            nCases = infectionHist;
            
            ft = fittype('iModel(x,a_n0,b_tau,c_Rstart,d_Rend,e_tOnset,f_slope,g_slope2)');
            options = fitoptions(ft);
            options.Lower =      [0,  5,app.Rstart,app.Rend,0,0,  0];
            options.Upper =      [inf,5,app.Rstart,app.Rend,inf,inf,inf];
            options.Startpoint = [N0, 5,app.Rstart,app.Rend,app.onset,app.slope,app.slope2];
            f = fit( app.nDay', nCases', ft, options)
            %             app.n0 = f.a_n0;
            %             app.onset = f.e_tOnset;
            %             app.slope = f.f_slope;
            %             app.slope2 = f.g_slope2;
            %             app.generateData();
            %             app.simulateDeaths(1);
            [fittedData, ~] = iModel(app.nDay,f.a_n0,f.b_tau,f.c_Rstart,f.d_Rend,f.e_tOnset,f.f_slope,f.g_slope2);
            
            
            %             delete(app.simDataInfections)
            %             hold(app.casesPlot,"on")
            % %             plot(app.casesPlot,app.nDay, fittedData/f.a_n0, '-k'); hold(app.casesPlot,"on");
            % %             plot(app.casesPlot,app.nDay, app.nCases*f.a_n0, '.-b'); hold(app.casesPlot,"on");
            % %             plot(app.casesPlot,app.nDay, nCases, '-', 'Color', [.5 .5 .5 .5]);
            %             app.simDataInfections = bar(app.casesPlot,app.nDay, nCases, 'FaceColor',[0 0 0],"FaceAlpha",0.3);
            hold(app.casesPlot,"off")
            hold(app.deathsPlot,"off")
            app.SimButton.Value = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global rmodel;
            rmodel = 'genlog';
            
            app.Ndays = 200;
            app.tau = 5;
            app.Rstart = 3;
            app.Rend = 0.7;
            app.onset = 20;
            app.slope = 0.5; %exponenetial
            app.slope2 = 0.5; %step
            app.n0 = 1;
            app.savedModels = {};
            app.nDeaths = zeros(1,app.Ndays);
            app.nDeathsDist = zeros(1,app.Ndays);
            app.nCases = zeros(1,app.Ndays);
            app.nDay = (1:app.Ndays);
            app.Re = zeros(1,app.Ndays);
            app.simNGenerations = 50;
            %             app.country = 0;
            app.countries = struct;
            app.latestSimDataInfections = bar(app.casesPlot,[],[]);
            app.latestSimStatusMessage = '-';
            
            app.RstartSlider.Value = log10(app.Rstart);
            app.RstartSpinner.Value = app.Rstart;
            app.RendSlider.Value = log10(app.Rend);
            app.RendSpinner.Value = app.Rend;
            app.N0Spinner.Value = app.n0;
            app.n0Slider.Value = log10(app.n0);
            app.onsetSlider.Value = app.onset;
            app.supressionDateSpinner.Value = app.onset;
            app.RmodelDropDown.Value = 'sigmoid';
            app.LoadDataDropDown.Value = 'Load';
            app.simGenerations.Value = app.simNGenerations;
            app.LockyscaleCheckBox.Value = 0;
            app.RlockCheckBox.Value = 0;
            app.supressionSlopeSpinner.Value = app.slope;
            
            hold(app.deathsPlot,"off");
            delete(app.countryData);
            delete(app.simDataInfections);
            delete(app.simDataDeceased);
            drawnow
            
            %             app.RePlot.YDir = 'reverse';
            
            app.RePlot.InnerPosition(1) = app.casesPlot.InnerPosition(1);
            app.RePlot.InnerPosition(3) = app.casesPlot.InnerPosition(3);
            app.deathsPlot.InnerPosition(1) = app.casesPlot.InnerPosition(1);
            app.deathsPlot.InnerPosition(3) = app.casesPlot.InnerPosition(3);
            
            app.onsetSlider.Position(1) = app.casesPlot.InnerPosition(1);
            app.onsetSlider.Position(3) = app.casesPlot.InnerPosition(3);
            
            app.RstartSlider.Position(2) = app.RePlot.InnerPosition(2);
            app.RstartSlider.Position(4) = app.RePlot.InnerPosition(4);
            app.RendSlider.Position(2) = app.RePlot.InnerPosition(2);
            app.RendSlider.Position(4) = app.RePlot.InnerPosition(4);
            
            set(app.UIFigure, 'WindowButtonMotionFcn', @app.mouseMove);
            
            app.update(1);
            
            %             echo on;
        end

        % Value changing function: onsetSlider
        function onsetSliderValueChanging(app, event)
            app.onset = floor(event.Value);
            app.onsetSlider.Value = app.onset;
            app.supressionDateSpinner.Value = app.onset;
            app.update(1)
        end

        % Value changed function: onsetSlider
        function onsetSliderValueChanged(app, event)
            app.simulateDeaths(1)
        end

        % Value changing function: RstartSlider
        function RstartSliderValueChanging(app, event)
            app.Rstart = 10^event.Value;
            app.RstartSpinner.Value = app.Rstart;
            if (app.RlockCheckBox.Value)
                app.Rend = app.Rstart;
                app.RendSlider.Value = log10(app.Rstart);
                app.RendSpinner.Value = app.Rstart;
            end
            app.update(1)
        end

        % Value changed function: RstartSlider
        function RstartSliderValueChanged(app, event)
            value = app.RstartSlider.Value;
            app.simulateDeaths(1)
        end

        % Value changed function: RstartSpinner
        function RstartSpinnerValueChanged(app, event)
            app.Rstart = app.RstartSpinner.Value;
            app.RstartSlider.Value = log10(app.Rstart);
            if (app.RlockCheckBox.Value)
                app.Rend = app.Rstart;
                app.RendSlider.Value = log10(app.Rstart);
                app.RendSpinner.Value = app.Rstart;
            end
            app.update(1)
        end

        % Value changing function: RendSlider
        function RendSliderValueChanging(app, event)
            app.Rend = 10^event.Value;
            app.RendSpinner.Value = app.Rend;
            if (app.RlockCheckBox.Value)
                app.Rstart = app.Rend;
                app.RstartSlider.Value = log10(app.Rend);
                app.RstartSpinner.Value = app.Rend;
            end
            app.update(1);
            
        end

        % Value changed function: RendSlider
        function RendSliderValueChanged(app, event)
            value = app.RendSlider.Value;
            app.simulateDeaths(1)
        end

        % Value changed function: RendSpinner
        function RendSpinnerValueChanged(app, event)
            app.Rend = app.RendSpinner.Value;
            app.RendSlider.Value = log10(app.Rend);
            if (app.RlockCheckBox.Value)
                app.Rstart = app.Rend;
                app.RstartSlider.Value = log10(app.Rend);
                app.RstartSpinner.Value = app.Rend;
            end
            app.update(1)
        end

        % Value changed function: RlockCheckBox
        function RlockCheckBoxValueChanged(app, event)
            value = app.RlockCheckBox.Value;
            if (value)
                app.Rend = app.Rstart;
                app.RendSlider.Value = log10(app.Rstart);
                app.RendSpinner.Value = app.Rstart;
                app.generateData();
                %             else
                %                 app.Rend = 0.7;
                %                 app.RendSlider.Value = log10(0.7);
                %                 app.RendSpinner.Value = 0.7;
                %                 app.generateData();
            end
            app.update(1)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.savedModels = [app.savedModels, [app.nDay; app.nCases; app.nDeathsDist; app.Re]];
            app.update(0);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.savedModels = {};
            delete(app.latestSimDataInfections)
            app.update(0);
        end

        % Callback function
        function DeathsButtonPushed(app, event)
            app.simulateDeaths(1)
        end

        % Value changing function: n0Slider
        function n0SliderValueChanging(app, event)
            app.n0 = 10^event.Value;
            app.N0Spinner.Value = app.n0;
            app.update(1)
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.timeShift = changingValue;
            app.simulateDeaths(1)
        end

        % Value changed function: LockyscaleCheckBox
        function LockyscaleCheckBoxValueChanged(app, event)
            value = app.LockyscaleCheckBox.Value;
            if (value)
                set(app.casesPlot,'YLimMode','manual');
            else
                set(app.casesPlot,'YLimMode','auto');
            end
            app.generateData();
        end

        % Value changed function: LoadDataDropDown
        function LoadDataDropDownValueChanged(app, event)
            value = app.LoadDataDropDown.Value;
            if strcmp(value,'Load')
                return
            end
            country = Country(value,0);
            %             app.country = country;
            app.countries.(country.name) = country;
            app.update(0);
            
        end

        % Button pushed function: fitButton
        function fitButtonPushed(app, event)
            ts = floor(app.timeShift);
            %             range = app.country.firstCaseDay:app.country.firstCaseDay+app.Ndays-1-ts;
            range = 30:30+app.Ndays-1-ts;
            %             model = dModel(app.nDay(:)',app.n0,app.tau,app.Rstart,app.Rend,app.onset,app.slope,app.slope2);
            %             plot(...
            %                 app.nDay, ...
            %                 [zeros(1,ts), movmean(app.countries.(app.LoadDataDropDown.Value).deaths(range), 7)], ...
            %                 '.-', app.nDay, model,'-'); hold on;
            
            rStartUpperLimit = app.iff(app.lockRstartCheckBox.Value, app.Rstart, inf);
            rStartLowerLimit = app.iff(app.lockRstartCheckBox.Value, app.Rstart, 0);
            onsetUpperLimit = app.iff(app.lockRsupCheckBox.Value, app.onset, inf);
            onsetLowerLimit = app.iff(app.lockRsupCheckBox.Value, app.onset, 0);
            
            ft = fittype('dModel(x,a_n0,b_tau,c_Rstart,d_Rend,e_tOnset,f_slope,g_slope2)');
            options = fitoptions(ft);
            options.Lower =      [0     5        rStartLowerLimit          0        onsetLowerLimit 0         app.slope2];
            options.Upper =      [inf   5        rStartUpperLimit          1        onsetUpperLimit 10        app.slope2];
            options.Startpoint = [app.n0,app.tau,app.Rstart,app.Rend,app.onset,app.slope,app.slope2];
            
            f = fit(app.nDay(:),[zeros(1,ts), movmean(app.countries.(app.LoadDataDropDown.Value).deaths(range), 1)]', ft, options)
            
            % plot(app.nDay,feval(f,app.nDay')); hold off;
            
            app.n0 = f.a_n0;
            try
                app.n0Slider.Value = log10(f.a_n0);
                app.N0Spinner.Value = f.a_n0;
            catch
            end
            app.Rstart = f.c_Rstart;
            % app.RstartSlider.Value = f.c_Rstart;
            app.RstartSpinner.Value = f.c_Rstart;
            app.Rend = f.d_Rend;
            % app.RendSlider.Value = f.d_Rend;
            app.RendSpinner.Value = f.d_Rend;
            app.onset = f.e_tOnset;
            app.onsetSlider.Value = f.e_tOnset;
            app.supressionDateSpinner.Value = f.e_tOnset;
            app.slope = f.f_slope;
            app.supressionSlopeSpinner.Value = f.f_slope;
            
            app.update(1);
        end

        % Value changed function: supressionDateSpinner
        function supressionDateSpinnerValueChanged(app, event)
            app.onset = app.supressionDateSpinner.Value;
            app.update(1);
        end

        % Value changed function: SimButton
        function simButtonValueChanged(app, event)
            app.simulateInfections();
        end

        % Value changed function: RmodelDropDown
        function RmodelDropDownValueChanged(app, event)
            global rmodel
            %             runStartupFcn(app, @startupFcn);
            rmodel = app.RmodelDropDown.Value;
            app.update(1);
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            if (key == 'r')
                runStartupFcn(app, @startupFcn);
            end
            if (key == 'z')
                app.casesPlot.XLim = [1,app.simNGenerations+0.5];
                app.casesPlot.XTickMode = 'auto';
                app.casesPlot.YLimMode = 'auto';
                app.deathsPlot.XLim = [1,app.simNGenerations+0.5];
                app.deathsPlot.XTickMode = 'auto';
                app.deathsPlot.YLimMode = 'auto';
                %                 app.generateData();
                app.simulateDeaths(1);
            end
            if (key == 'x')
                app.casesPlot.XLim = [0,app.Ndays];
                app.casesPlot.XTickMode = 'auto';
                app.casesPlot.YLimMode = 'auto';
                app.deathsPlot.XLim = [0,app.Ndays];
                app.deathsPlot.XTickMode = 'auto';
                app.deathsPlot.YLimMode = 'auto';
                app.simGenerations.Value = 100;
                app.simNGenerations = 50;
                app.update(1);
            end
            if (key == 'l')
                if (strcmp(app.casesPlot.YScale,'log'))
                    app.casesPlot.YScale = 'linear';
                else
                    app.casesPlot.YScale = 'log';
                end
            end
            
        end

        % Value changed function: N0Spinner
        function N0SpinnerValueChanged(app, event)
            app.n0 = app.N0Spinner.Value;
            app.n0Slider.Value = log10(app.n0);
            app.update(1);
        end

        % Value changed function: simGenerations
        function simGenerationsValueChanged(app, event)
            app.simNGenerations = app.simGenerations.Value;
            %             app.casesPlot.XLim = [1,app.simNGenerations+0.5];
            %             app.casesPlot.XTickMode = 'auto';
            %             app.casesPlot.YLimMode = 'auto';
            %             app.deathsPlot.XLim = [1,app.simNGenerations+0.5];
            %             app.deathsPlot.XTickMode = 'auto';
            %             app.deathsPlot.YLimMode = 'auto';
            %             %             app.generateData();
            %             app.simulateDeaths(1);
        end

        % Value changed function: supressionSlopeSpinner
        function supressionSlopeSpinnerValueChanged(app, event)
            app.slope = app.supressionSlopeSpinner.Value;
            app.update(1);
        end

        % Value changing function: xZoomSlider
        function xZoomSliderValueChanging(app, event)
            changingValue = event.Value;
            app.casesPlot.YLimMode = 'auto';
            app.deathsPlot.YLimMode = 'auto';
            app.RePlot.XLim(2) = changingValue;
            app.casesPlot.XLim(2) = changingValue;
            app.deathsPlot.XLim(2) = changingValue;
            app.RePlot.XTickMode = 'auto';
            app.casesPlot.XTickMode = 'auto';
            app.deathsPlot.XTickMode = 'auto';
            
            %             max(findobj(app.casesPlot.Children,'LineStyle','-','Marker','.').YData)
            
            %             app.casesPlot.YLim(2) = max(findobj(app.casesPlot.Children,'LineStyle',':','Color',[0 0 0 0.5]).YData);
            %             app.deathsPlot.YLim(2) = max(findobj(app.deathsPlot.Children,'LineStyle',':','Color',[0 0 0 0.5]).YData);
            
        end

        % Menu selected function: resetMenu
        function resetMenuSelected(app, event)
            runStartupFcn(app, @startupFcn);
        end

        % Menu selected function: togglelinlogMenu
        function togglelinlogMenuSelected(app, event)
            if (strcmp(app.casesPlot.YScale,'log'))
                app.casesPlot.YScale = 'linear';
            else
                app.casesPlot.YScale = 'log';
            end
        end

        % Menu selected function: togglesimstatusMenu
        function togglesimstatusMenuSelected(app, event)
            if strcmp(app.togglesimstatusMenu.Checked,'on')
                app.togglesimstatusMenu.Checked = 'off';
                app.simStatusTextArea.Visible = 'off';
            else
                app.togglesimstatusMenu.Checked = 'on';
                app.simStatusTextArea.Visible = 'on';
            end
        end

        % Value changed function: lockRstartCheckBox
        function lockRstartCheckBoxValueChanged(app, event)
            value = app.lockRstartCheckBox.Value
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [0 100];
            app.Slider.MajorTickLabels = {'0', '100'};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.onsetSlider = uislider(app.UIFigure);
            app.onsetSlider.Limits = [0 200];
            app.onsetSlider.MajorTicks = [];
            app.onsetSlider.MajorTickLabels = {''};
            app.onsetSlider.ValueChangedFcn = createCallbackFcn(app, @onsetSliderValueChanged, true);
            app.onsetSlider.ValueChangingFcn = createCallbackFcn(app, @onsetSliderValueChanging, true);
            app.onsetSlider.MinorTicks = [];
            app.onsetSlider.Value = 20;
            app.LockyscaleCheckBox = uicheckbox(app.UIFigure);
            app.LockyscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockyscaleCheckBoxValueChanged, true);
            app.LockyscaleCheckBox.Text = 'Lock y-scale';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RstartSlider = uislider(app.UIFigure);
            app.RstartSlider.Limits = [-1 1];
            app.RstartSlider.MajorTicks = [];
            app.RstartSlider.ValueChangedFcn = createCallbackFcn(app, @RstartSliderValueChanged, true);
            app.RstartSlider.ValueChangingFcn = createCallbackFcn(app, @RstartSliderValueChanging, true);
            app.RstartSlider.MinorTicks = [];
            app.RstartSlider.Value = 0.477121254719662;
            app.RendSlider = uislider(app.UIFigure);
            app.RendSlider.Limits = [-1 1];
            app.RendSlider.MajorTicks = [];
            app.RendSlider.ValueChangedFcn = createCallbackFcn(app, @RendSliderValueChanged, true);
            app.RendSlider.ValueChangingFcn = createCallbackFcn(app, @RendSliderValueChanging, true);
            app.RendSlider.MinorTicks = [];
            app.RendSlider.Value = -0.154901959985743;
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.n0Slider = uislider(app.UIFigure);
            app.n0Slider.Limits = [-3 3];
            app.n0Slider.MajorTicks = [-3 0 3];
            app.n0Slider.MajorTickLabels = {'0.001', '1', '1000'};
            app.n0Slider.ValueChangingFcn = createCallbackFcn(app, @n0SliderValueChanging, true);
            app.n0Slider.MinorTicks = [];
            app.LoadDataDropDown = uidropdown(app.UIFigure);
            app.LoadDataDropDown.Items = {'Load Data', 'Belgium', 'Denmark', 'Sweden', 'Spain', 'Italy', 'France'};
            app.LoadDataDropDown.ItemsData = {'Load', 'Belgium', 'Denmark', 'Sweden', 'Spain', 'Italy', 'France'};
            app.LoadDataDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadDataDropDownValueChanged, true);
            app.LoadDataDropDown.Value = 'Load';
            app.RlockCheckBox = uicheckbox(app.UIFigure);
            app.RlockCheckBox.ValueChangedFcn = createCallbackFcn(app, @RlockCheckBoxValueChanged, true);
            app.RlockCheckBox.Text = '';
            app.RendSpinner = uispinner(app.UIFigure);
            app.RendSpinner.Step = 0.05;
            app.RendSpinner.ValueDisplayFormat = '%.2f';
            app.RendSpinner.ValueChangedFcn = createCallbackFcn(app, @RendSpinnerValueChanged, true);
            app.RendSpinner.Value = 0.7;
            app.delayEditFieldLabel = uilabel(app.UIFigure);
            app.delayEditFieldLabel.Text = 'delay';
            app.delayInput = uieditfield(app.UIFigure, 'numeric');
            app.delayInput.Tooltip = {'Infection Fatality Rate. The fraction of all infected that dies.'};
            app.delayInput.Value = 20;
            app.serialintervalEditFieldLabel = uilabel(app.UIFigure);
            app.serialintervalEditFieldLabel.Text = 'serial interval';
            app.serialintervalEditField = uieditfield(app.UIFigure, 'numeric');
            app.serialintervalEditField.Tooltip = {'Infection Fatality Rate. The fraction of all infected that dies.'};
            app.serialintervalEditField.Value = 5;
            app.IFREditFieldLabel = uilabel(app.UIFigure);
            app.IFREditFieldLabel.Text = 'IFR';
            app.IFREditField = uieditfield(app.UIFigure, 'numeric');
            app.IFREditField.Tooltip = {'Infection Fatality Rate. The fraction of all infected that dies.'};
            app.IFREditField.Value = 0.01;
            app.varianceEditField_2Label = uilabel(app.UIFigure);
            app.varianceEditField_2Label.Text = 'variance';
            app.varianceInput = uieditfield(app.UIFigure, 'numeric');
            app.varianceInput.Tooltip = {'Infection Fatality Rate. The fraction of all infected that dies.'};
            app.varianceInput.Value = 20;
            app.fitButton = uibutton(app.UIFigure, 'push');
            app.fitButton.ButtonPushedFcn = createCallbackFcn(app, @fitButtonPushed, true);
            app.fitButton.Text = 'Fit';
            app.RstartSpinner = uispinner(app.UIFigure);
            app.RstartSpinner.ValueDisplayFormat = '%.2f';
            app.RstartSpinner.ValueChangedFcn = createCallbackFcn(app, @RstartSpinnerValueChanged, true);
            app.RstartSpinner.Value = 3;
            app.RmodelDropDownLabel = uilabel(app.UIFigure);
            app.RmodelDropDownLabel.Text = 'R model';
            app.RmodelDropDown = uidropdown(app.UIFigure);
            app.RmodelDropDown.Items = {'sigmoid', 'exponential', 'step', 'logistic'};
            app.RmodelDropDown.ItemsData = {'sigmoid', 'exponential', 'step', 'genlog'};
            app.RmodelDropDown.ValueChangedFcn = createCallbackFcn(app, @RmodelDropDownValueChanged, true);
            app.RmodelDropDown.Value = 'sigmoid';
            app.N0SpinnerLabel = uilabel(app.UIFigure);
            app.N0SpinnerLabel.Text = 'N0';
            app.N0Spinner = uispinner(app.UIFigure);
            app.N0Spinner.Step = 0.2;
            app.N0Spinner.Limits = [0.001 1000];
            app.N0Spinner.ValueDisplayFormat = '%.1f';
            app.N0Spinner.ValueChangedFcn = createCallbackFcn(app, @N0SpinnerValueChanged, true);
            app.N0Spinner.Value = 1;
            app.simGenerations = uieditfield(app.UIFigure, 'numeric');
            app.simGenerations.Limits = [1 200];
            app.simGenerations.ValueChangedFcn = createCallbackFcn(app, @simGenerationsValueChanged, true);
            app.simGenerations.Value = 50;
            app.SimButton = uibutton(app.UIFigure, 'state');
            app.SimButton.ValueChangedFcn = createCallbackFcn(app, @simButtonValueChanged, true);
            app.SimButton.Text = 'Sim';
            app.slopeLabel = uilabel(app.UIFigure);
            app.slopeLabel.Text = 'slope';
            app.supressionSlopeSpinner = uispinner(app.UIFigure);
            app.supressionSlopeSpinner.Step = 0.1;
            app.supressionSlopeSpinner.Limits = [0 Inf];
            app.supressionSlopeSpinner.ValueDisplayFormat = '%.2f';
            app.supressionSlopeSpinner.ValueChangedFcn = createCallbackFcn(app, @supressionSlopeSpinnerValueChanged, true);
            app.supressionSlopeSpinner.Value = 0.5;
            app.RsupressiondaySpinnerLabel = uilabel(app.UIFigure);
            app.RsupressiondaySpinnerLabel.Text = 'R supression day';
            app.supressionDateSpinner = uispinner(app.UIFigure);
            app.supressionDateSpinner.ValueChangedFcn = createCallbackFcn(app, @supressionDateSpinnerValueChanged, true);
            app.supressionDateSpinner.Value = 20;
            app.DateLabel = uilabel(app.UIFigure);
            app.DateLabel.Text = '';
            app.lockRsupCheckBox = uicheckbox(app.UIFigure);
            app.lockRsupCheckBox.Text = '🔒';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.xZoomSlider = uislider(app.UIFigure);
            app.xZoomSlider.Limits = [5 200];
            app.xZoomSlider.MajorTicks = [];
            app.xZoomSlider.ValueChangingFcn = createCallbackFcn(app, @xZoomSliderValueChanging, true);
            app.xZoomSlider.MinorTicks = [];
            app.xZoomSlider.Value = 200;
            app.lockRstartCheckBox = uicheckbox(app.UIFigure);
            app.lockRstartCheckBox.ValueChangedFcn = createCallbackFcn(app, @lockRstartCheckBoxValueChanged, true);
            app.lockRstartCheckBox.Text = '🔒';
            app.simStatusTextArea = uitextarea(app.UIFigure);
            app.simStatusTextArea.Visible = 'off';
            app.casesPlot = uiaxes(app.UIFigure);
            zlabel(app.casesPlot, 'Z')
            app.casesPlot.XLim = [0 200];
            app.casesPlot.XTick = [20 40 60 80 100 120 140 160 180];
            app.RePlot = uiaxes(app.UIFigure);
            zlabel(app.RePlot, 'Z')
            app.RePlot.XLim = [0 200];
            app.RePlot.YLim = [0.5 5];
            app.RePlot.XTick = [20 40 60 80 100 120 140 160 180];
            app.deathsPlot = uiaxes(app.UIFigure);
            xlabel(app.deathsPlot, 'days')
            zlabel(app.deathsPlot, 'Z')
            app.deathsPlot.XLim = [0 200];
            app.deathsPlot.XTick = [20 40 60 80 100 120 140 160 180];
            app.MainContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.MainContextMenu
            app.UIFigure.ContextMenu = app.MainContextMenu;
            app.resetMenu = uimenu(app.MainContextMenu);
            app.resetMenu.MenuSelectedFcn = createCallbackFcn(app, @resetMenuSelected, true);
            app.resetMenu.Accelerator = 'r';
            app.resetMenu.Text = 'reset';
            app.togglelinlogMenu = uimenu(app.MainContextMenu);
            app.togglelinlogMenu.MenuSelectedFcn = createCallbackFcn(app, @togglelinlogMenuSelected, true);
            app.togglelinlogMenu.Accelerator = 'l';
            app.togglelinlogMenu.Text = 'toggle lin log';
            app.togglesimstatusMenu = uimenu(app.MainContextMenu);
            app.togglesimstatusMenu.MenuSelectedFcn = createCallbackFcn(app, @togglesimstatusMenuSelected, true);
            app.togglesimstatusMenu.Checked = 'on';
            app.togglesimstatusMenu.Text = 'toggle sim status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end