classdef app1_2017 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TimeShiftSliderLabel          matlab.ui.control.Label
        TimeShiftSlider               matlab.ui.control.Slider
        onsetSlider                   matlab.ui.control.Slider
        LockyscaleCheckBox            matlab.ui.control.CheckBox
        SaveButton                    matlab.ui.control.Button
        RstartSlider                  matlab.ui.control.Slider
        RendSlider                    matlab.ui.control.Slider
        ClearButton                   matlab.ui.control.Button
        infectedStatBox               matlab.ui.control.Label
        n0Slider                      matlab.ui.control.Slider
        LoadDataDropDownLabel         matlab.ui.control.Label
        LoadDataDropDown              matlab.ui.control.DropDown
        Rlock                         matlab.ui.control.CheckBox
        RendSpinner                   matlab.ui.control.Spinner
        delayEditFieldLabel           matlab.ui.control.Label
        delayInput                    matlab.ui.control.NumericEditField
        serialintervalEditFieldLabel  matlab.ui.control.Label
        serialintervalEditField       matlab.ui.control.NumericEditField
        IFREditFieldLabel             matlab.ui.control.Label
        IFREditField                  matlab.ui.control.NumericEditField
        varianceEditField_2Label      matlab.ui.control.Label
        varianceInput                 matlab.ui.control.NumericEditField
        fitButton                     matlab.ui.control.Button
        RstartSpinner                 matlab.ui.control.Spinner
        onsetSpinner                  matlab.ui.control.Spinner
        simButton                     matlab.ui.control.Button
        fitInfectedButton             matlab.ui.control.Button
        RmodelDropDownLabel           matlab.ui.control.Label
        RmodelDropDown                matlab.ui.control.DropDown
        N0SpinnerLabel                matlab.ui.control.Label
        N0Spinner                     matlab.ui.control.Spinner
        casesPlot                     matlab.ui.control.UIAxes
        RePlot                        matlab.ui.control.UIAxes
        deathsPlot                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        timeShift = 0 % Description
        tau % Description
        Rstart % Description
        onset % Description
        slope % Description
        Ndays % Description
        savedModels % Description
        nCases % Description
        Re % Description
        nDay % Description
        n0 % Description
        nDeaths% Description
        nDeathsDist % Description
        country % Description
        slope2 % Description
        Rend % Description
    end
    
    methods (Access = private)
        function results = generateData(app)

            app.casesPlot.PositionConstraint = 'innerposition';
            app.RePlot.PositionConstraint = 'innerposition';
            
            maxCases = 0;
            
            [app.nCases, app.Re] = iModel(app.nDay, app.n0, app.tau, app.Rstart, app.Rend, app.onset, app.slope, app.slope2);
            app.nCases = app.nCases';
%             [~, app.nCases, app.Re] = dModel(app.nDay, app.n0, app.tau, app.Rstart, app.Rend, app.onset, app.slope, app.slope2);
            if (max(app.nCases) > maxCases) 
                maxCases = max(app.nCases);
            end
            zeroCross = app.zeroCrossing(app.Re-1);
            
            plot(app.casesPlot, app.nDay, app.nCases, '.-b');
            hold(app.casesPlot,'on');
            plot(app.RePlot, app.nDay, app.Re, '.-r');
            hold(app.RePlot,'on');
            for i = 1:length(app.savedModels)
                data = app.savedModels{i};
                if (max(data(2,:)) > maxCases) 
                    maxCases = max(max(data(2,:)));
                end
                plot(app.casesPlot, data(1,:), data(2,:), '.-','Color',[0.5 0.5 0.5]);
                plot(app.RePlot, data(1,:), data(4,:), '.-','Color',[0.5 0.5 0.5]);
            end
            if (~app.LockyscaleCheckBox.Value)
                ylim(app.casesPlot, [0, maxCases*1.2]);
            end

            plot(app.casesPlot, [zeroCross,zeroCross], [0,app.casesPlot.YLim(2)], ':r', "LineWidth", 0.1);
            plot(app.RePlot, [zeroCross,zeroCross], [app.RePlot.YLim(1),app.RePlot.YLim(2)], ':r',  "LineWidth", 0.1);
            plot(app.RePlot, [0,app.Ndays], [1,1], '--r',  "LineWidth", 0.1);

%             if (strcmp(class(app.country),'Country'))
%                 app.country.firstCaseDay
%                 range = app.country.firstCaseDay:app.country.firstCaseDay+app.Ndays-1;
%                 plot(app.casesPlot, ...
%                     app.nDay, ...
%                     movmean(100*app.country.deaths(range), 7), ...
%                     '.-') 
%             end
            hold(app.casesPlot,'off');
            hold(app.RePlot,'off');
            
            infectionRatio = '-';
            if (strcmp(class(app.country),'Country'))
                infectionRatio = sprintf('%.0f',100*sum(app.nCases)/app.country.population);
            end
            app.infectedStatBox.Text = sprintf('      n0: %-g\ninfected: %-.0f (%s%% of population)', app.n0, sum(app.nCases), infectionRatio);
            
            text(app.casesPlot,0.02,0.95,'New Infections','Units','normalized','Color','b');
            text(app.RePlot,0.02,0.95,'Re (effective Reproduction number)','Units','normalized','Color','r');

        end
        
        function results = simulateDeaths(app, resimulate)

            app.deathsPlot.PositionConstraint = 'innerposition';

            %
            if (resimulate)
                deathDays = [];
                %deathPdf = poisspdf(0:39,20);
                deathPdf = lognpdf(1:40,log(20),0.2);
                app.nDeathsDist = zeros(1,app.Ndays);
                for nd = app.nDay
                    if (nd < app.Ndays-38) 
                         app.nDeathsDist(nd:nd+40-1) = app.nDeathsDist(nd:nd+40-1) + 0.01*app.nCases(nd)*deathPdf;
                    else
                        nLeft = app.Ndays - nd;
                        app.nDeathsDist(nd:nd+nLeft) = app.nDeathsDist(nd:nd+nLeft) + 0.01*app.nCases(nd)*deathPdf(1:nLeft+1);
                    end
                end
            end
            
            % plot death data
            plot(app.deathsPlot, app.nDay, app.nDeathsDist, '.-k')
            hold(app.deathsPlot,"on");
            for i = 1:length(app.savedModels)
                data = app.savedModels{i};
                plot(app.deathsPlot, data(1,:), movmean(data(3,:),1), '.-','Color',[0.5 0.5 0.5]);
            end
            if (strcmp(class(app.country),'Country'))
                ts = floor(app.timeShift);
                %range = app.country.firstCaseDay:app.country.firstCaseDay+app.Ndays-1-ts;
                range = 30:30+app.Ndays-1-ts;
                plot(app.deathsPlot, ...
                    app.nDay, ...
                    [zeros(1,ts), movmean(app.country.deaths(range), 7)], ...
                    '.-') 
%                 plot(app.country.days,movmean(app.country.deaths,14)); hold on
            end
            hold(app.deathsPlot,"off");
            
            text(app.deathsPlot,0.02,0.95,'Deceased','Units','normalized','Color','k');
        end
        
        function result = zeroCrossing(app,y)
            try
                zc = find(y(:).*circshift(y(:), [-1 0]) <= 0);
                result = zc(1);
            catch
                result = -1;
            end
        end    
        
        function mouseMove(app, objectHandle, event)
%             try
%                  floor(app.RePlot.CurrentPoint(1,1))
%                  app.country.dates(floor(app.RePlot.CurrentPoint(1,1))+30)
%             catch
%             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global rmodel;
            rmodel = 'genlog';

            app.Ndays = 200;
            app.tau = 5;
            app.Rstart = 3;
            app.Rend = 0.7;
            app.onset = 20;
            app.slope = 0.5; %exponenetial
            app.slope2 = 0.5; %step
            app.n0 = 1;
            app.savedModels = {};
            app.nDeaths = zeros(1,app.Ndays);
            app.nDeathsDist = zeros(1,app.Ndays);
            app.nCases = zeros(1,app.Ndays);
            app.nDay = (1:app.Ndays);
            app.Re = zeros(1,app.Ndays);

            app.RstartSlider.Value = log10(app.Rstart);
            app.RstartSpinner.Value = app.Rstart;
            app.RendSlider.Value = log10(app.Rend);
            app.RendSpinner.Value = app.Rend;
            app.N0Spinner.Value = app.n0;
            app.n0Slider.Value = log10(app.n0);
            app.onsetSlider.Value = app.onset;
            app.onsetSpinner.Value = app.onset;
            app.RmodelDropDown.Value = 'sigmoid';
            app.LoadDataDropDown.Value = ' ';
            
            app.RePlot.InnerPosition(1) = app.casesPlot.InnerPosition(1);
            app.RePlot.InnerPosition(3) = app.casesPlot.InnerPosition(3);
            app.deathsPlot.InnerPosition(1) = app.casesPlot.InnerPosition(1);
            app.deathsPlot.InnerPosition(3) = app.casesPlot.InnerPosition(3);

            app.onsetSlider.Position(1) = app.casesPlot.InnerPosition(1);
            app.onsetSlider.Position(3) = app.casesPlot.InnerPosition(3);

            app.RstartSlider.Position(2) = app.RePlot.InnerPosition(2);
            app.RstartSlider.Position(4) = app.RePlot.InnerPosition(4);
            app.RendSlider.Position(2) = app.RePlot.InnerPosition(2);
            app.RendSlider.Position(4) = app.RePlot.InnerPosition(4);

            set(app.UIFigure, 'WindowButtonMotionFcn', @app.mouseMove); 

            app.generateData();
            app.simulateDeaths(1);
            
%             echo on;
        end

        % Value changing function: onsetSlider
        function onsetSliderValueChanging(app, event)
            app.onset = floor(event.Value);
            app.onsetSlider.Value = app.onset;
            app.onsetSpinner.Value = app.onset;
            app.generateData();
            app.simulateDeaths(1)
        end

        % Value changed function: onsetSlider
        function onsetSliderValueChanged(app, event)
            app.simulateDeaths(1)
        end

        % Value changing function: RstartSlider
        function RstartSliderValueChanging(app, event)
            app.Rstart = 10^event.Value;
            app.RstartSpinner.Value = app.Rstart;
            if (app.Rlock.Value)
                app.Rend = app.Rstart;
                app.RendSlider.Value = log10(app.Rstart);
                app.RendSpinner.Value = app.Rstart;
            end
            app.generateData();
            app.simulateDeaths(1)
        end

        % Value changed function: RstartSlider
        function RstartSliderValueChanged(app, event)
            value = app.RstartSlider.Value;
            app.simulateDeaths(1)
        end

        % Value changed function: RstartSpinner
        function RstartSpinnerValueChanged(app, event)
            app.Rstart = app.RstartSpinner.Value;
            app.RstartSlider.Value = log10(app.Rstart);
            if (app.Rlock.Value)
                app.Rend = app.Rstart;
                app.RendSlider.Value = log10(app.Rstart);
                app.RendSpinner.Value = app.Rstart;
            end
            app.generateData();
            app.simulateDeaths(1)
        end

        % Value changing function: RendSlider
        function RendSliderValueChanging(app, event)
            app.Rend = 10^event.Value;
            app.RendSpinner.Value = app.Rend;
            if (app.Rlock.Value)
                app.Rstart = app.Rend;
                app.RstartSlider.Value = log10(app.Rend);
                app.RstartSpinner.Value = app.Rend;
            end
            app.generateData();
            app.simulateDeaths(1)
        end

        % Value changed function: RendSlider
        function RendSliderValueChanged(app, event)
            value = app.RendSlider.Value;
            app.simulateDeaths(1)
        end

        % Value changed function: RendSpinner
        function RendSpinnerValueChanged(app, event)
            app.Rend = app.RendSpinner.Value;
            app.RendSlider.Value = log10(app.Rend);
            if (app.Rlock.Value)
                app.Rstart = app.Rend;
                app.RstartSlider.Value = log10(app.Rend);
                app.RstartSpinner.Value = app.Rend;
            end
            app.generateData();
            app.simulateDeaths(1)
        end

        % Value changed function: Rlock
        function RlockValueChanged(app, event)
            value = app.Rlock.Value;
            if (value) 
                app.Rend = app.Rstart;
                app.RendSlider.Value = log10(app.Rstart);
                app.RendSpinner.Value = app.Rstart;
                app.generateData();
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            size(app.nDay)
            size(app.nCases)
            size(app.nDeaths)
            size(app.Re)
            app.savedModels = [app.savedModels, [app.nDay; app.nCases; app.nDeaths; app.Re]];
            app.generateData();
            app.simulateDeaths(0);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.savedModels = {};
            app.generateData();
            app.simulateDeaths(0);
        end

        % Callback function
        function DeathsButtonPushed(app, event)
            app.simulateDeaths(1)
        end

        % Value changing function: n0Slider
        function n0SliderValueChanging(app, event)
            app.n0 = 10^event.Value;
            app.N0Spinner.Value = app.n0;
            app.generateData();
            app.simulateDeaths(1)
        end

        % Value changing function: TimeShiftSlider
        function TimeShiftSliderValueChanging(app, event)
            changingValue = event.Value;
            app.timeShift = changingValue;
            app.simulateDeaths(1)
        end

        % Value changed function: LockyscaleCheckBox
        function LockyscaleCheckBoxValueChanged(app, event)
            value = app.LockyscaleCheckBox.Value;
            if (value) 
                set(app.casesPlot,'YLimMode','manual');
            else
                set(app.casesPlot,'YLimMode','auto');
            end
            app.generateData();
        end

        % Value changed function: LoadDataDropDown
        function LoadDataDropDownValueChanged(app, event)
            value = app.LoadDataDropDown.Value;
            app.country = Country(value,0);
            app.generateData();
            app.simulateDeaths(0);
        end

        % Button pushed function: fitButton
        function fitButtonPushed(app, event)
            ts = floor(app.timeShift);
%             range = app.country.firstCaseDay:app.country.firstCaseDay+app.Ndays-1-ts;
            range = 30:30+app.Ndays-1-ts;
            model = dModel(app.nDay(:)',app.n0,app.tau,app.Rstart,app.Rend,app.onset,app.slope,app.slope2);
            plot(...
                app.nDay, ...
                [zeros(1,ts), movmean(app.country.deaths(range), 7)], ...
                '.-', app.nDay, model,'-'); hold on;

            ft = fittype('dModel(x,a_n0,b_tau,c_Rstart,d_Rend,e_tOnset,f_slope,g_slope2)');
            options = fitoptions(ft);
            options.Lower =      [0     5        3          0        10        0         app.slope2];
            options.Upper =      [inf   5        3          1        inf       inf       app.slope2];
            options.Startpoint = [app.n0,app.tau,app.Rstart,app.Rend,app.onset,app.slope,app.slope2];
            
            f = fit(app.nDay(:),[zeros(1,ts), movmean(app.country.deaths(range), 7)]', ft, options)
            plot(app.nDay,feval(f,app.nDay')); hold off;
            app.n0 = f.a_n0;
            app.n0Slider.Value = log10(f.a_n0);
            app.N0Spinner.Value = f.a_n0;
            app.Rstart = f.c_Rstart;
%             app.RstartSlider.Value = f.c_Rstart;
            app.RstartSpinner.Value = f.c_Rstart;
            app.Rend = f.d_Rend;
%             app.RendSlider.Value = f.d_Rend;
            app.RendSpinner.Value = f.d_Rend;
            app.onset = f.e_tOnset;
            app.onsetSlider.Value = f.e_tOnset;
            app.onsetSpinner.Value = f.e_tOnset;
            app.slope = f.f_slope;
            app.generateData();
            app.simulateDeaths(1);
%             plot(f)

        end

        % Value changed function: onsetSpinner
        function onsetSpinnerValueChanged(app, event)
            app.onset = app.onsetSpinner.Value;
            app.generateData();
            app.simulateDeaths(1);
        end

        % Button pushed function: simButton
        function simButtonPushed(app, event)
            N0 = 1;
            nInfecteds = N0;
            infecteds = [];
            infectionDay = [];
            infectionHist = zeros(size(app.nDay));
            nInf = 0;
            nRem = 0;
            
            disp(['DAY .... : ' num2str(0) ]);
            
            index = 1;
            for i=1:N0
                patientZero = Person(0, app.Rstart, -1, index);
                nInf = nInf +1;
                infecteds = [infecteds patientZero];
                infectionDay = [infectionDay 0];
            end

            for day = 1:app.Ndays/2
    
                nInfecteds = [nInfecteds length(infecteds(find([infecteds.suceptible] == 1)))];
                
                disp(['DAY .... : ' num2str(day) ' infected: ' num2str(length(infecteds(find([infecteds.suceptible] == 1)))) ' ' num2str(index)]);
                
                iInfected = 1;
                for infected = infecteds(find([infecteds.suceptible] == 1))
                    
                    for daysToInfectDay = infected.infectSchedule
                        if day == daysToInfectDay
                            newInfected = Person(day, app.Re(day), infected.index, index);
                            nInf = nInf +1;
                            index = index + 1;
                            infecteds = [infecteds newInfected];
                            infectionDay = [infectionDay day];
                            infectionHist(day) = infectionHist(day)+1;
                        end
                    end
                    
                    if day >= infected.removalDay
                        infected.suceptible = 0;
                        nRem = nRem + 1;
                    end
            
                    iInfected = iInfected + 1;
                end

% %                 nCases = hist(infectionDay, app.nDay);
% %                 nCases(nCases == 0) = nan;
% %                 hold(app.casesPlot,"on")
%                 bar(app.casesPlot,app.nDay, infectionHist);
% %                 hold(app.casesPlot,"off")
%                 drawnow

            end
            
%             nCases = hist(infectionDay,app.nDay);
            nCases = infectionHist;

            ft = fittype('iModel(x,a_n0,b_tau,c_Rstart,d_Rend,e_tOnset,f_slope,g_slope2)');
            options = fitoptions(ft);
            options.Lower =      [0,  5,app.Rstart,app.Rend,0,0,  0];
            options.Upper =      [inf,5,app.Rstart,app.Rend,inf,inf,inf];
            options.Startpoint = [N0, 5,app.Rstart,app.Rend,app.onset,app.slope,app.slope2];
            f = fit( app.nDay', nCases', ft, options)
%             app.n0 = f.a_n0;
%             app.onset = f.e_tOnset;
%             app.slope = f.f_slope;
%             app.slope2 = f.g_slope2;
%             app.generateData();
%             app.simulateDeaths(1);
            [fittedData, ~] = iModel(app.nDay,f.a_n0,f.b_tau,f.c_Rstart,f.d_Rend,f.e_tOnset,f.f_slope,f.g_slope2);

            hold(app.casesPlot,"off")
%             
            plot(app.casesPlot,app.nDay, fittedData/f.a_n0, '-k'); hold(app.casesPlot,"on");
            plot(app.casesPlot,app.nDay, app.nCases, '.-b'); hold(app.casesPlot,"on");
%             plot(app.casesPlot,app.nDay, nCases, '-', 'Color', [.5 .5 .5 .5]);
            bar(app.casesPlot,app.nDay, nCases/f.a_n0, 'FaceColor',[0 0 0],"FaceAlpha",0.3);
            hold(app.casesPlot,"off")

        end

        % Value changed function: RmodelDropDown
        function RmodelDropDownValueChanged(app, event)
            global rmodel
            app.startupFcn();
            rmodel = app.RmodelDropDown.Value;
            app.generateData();
            app.simulateDeaths(1);
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            if (key == 'r')
                runStartupFcn(app, @startupFcn);
            end
            if (key == 'z')
                app.casesPlot.XLim = [1,12];
                app.casesPlot.XTickMode = 'auto';
                app.casesPlot.YLimMode = 'auto';
                
            end
            if (key == 'x')
                app.casesPlot.XLim = [0,200];
                app.casesPlot.XTickMode = 'auto';
                app.casesPlot.YLimMode = 'auto';
                app.generateData();
            end

        end

        % Value changed function: N0Spinner
        function N0SpinnerValueChanged(app, event)
            app.n0 = app.N0Spinner.Value;
            app.n0Slider.Value = log10(app.n0);
            app.generateData();
            app.simulateDeaths(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.TimeShiftSliderLabel = uilabel(app.UIFigure);
            app.TimeShiftSliderLabel.Text = 'Time Shift';
            app.TimeShiftSlider = uislider(app.UIFigure);
            app.TimeShiftSlider.MajorTicks = [0 100];
            app.TimeShiftSlider.MajorTickLabels = {'0', '100'};
            app.TimeShiftSlider.ValueChangingFcn = createCallbackFcn(app, @TimeShiftSliderValueChanging, true);
            app.TimeShiftSlider.MinorTicks = [];
            app.onsetSlider = uislider(app.UIFigure);
            app.onsetSlider.Limits = [0 200];
            app.onsetSlider.MajorTicks = [];
            app.onsetSlider.MajorTickLabels = {''};
            app.onsetSlider.ValueChangedFcn = createCallbackFcn(app, @onsetSliderValueChanged, true);
            app.onsetSlider.ValueChangingFcn = createCallbackFcn(app, @onsetSliderValueChanging, true);
            app.onsetSlider.MinorTicks = [];
            app.onsetSlider.Value = 20;
            app.LockyscaleCheckBox = uicheckbox(app.UIFigure);
            app.LockyscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockyscaleCheckBoxValueChanged, true);
            app.LockyscaleCheckBox.Text = 'Lock y-scale';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RstartSlider = uislider(app.UIFigure);
            app.RstartSlider.Limits = [-1 1];
            app.RstartSlider.MajorTicks = [];
            app.RstartSlider.ValueChangedFcn = createCallbackFcn(app, @RstartSliderValueChanged, true);
            app.RstartSlider.ValueChangingFcn = createCallbackFcn(app, @RstartSliderValueChanging, true);
            app.RstartSlider.MinorTicks = [];
            app.RstartSlider.Value = 0.477121254719662;
            app.RendSlider = uislider(app.UIFigure);
            app.RendSlider.Limits = [-1 1];
            app.RendSlider.MajorTicks = [];
            app.RendSlider.ValueChangedFcn = createCallbackFcn(app, @RendSliderValueChanged, true);
            app.RendSlider.ValueChangingFcn = createCallbackFcn(app, @RendSliderValueChanging, true);
            app.RendSlider.MinorTicks = [];
            app.RendSlider.Value = -0.154901959985743;
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.infectedStatBox = uilabel(app.UIFigure);
            app.infectedStatBox.Text = {'      n0:'; 'Infected:'; ''};
            app.n0Slider = uislider(app.UIFigure);
            app.n0Slider.Limits = [-3 3];
            app.n0Slider.MajorTicks = [-3 0 3];
            app.n0Slider.MajorTickLabels = {'0.001', '1', '1000'};
            app.n0Slider.ValueChangingFcn = createCallbackFcn(app, @n0SliderValueChanging, true);
            app.n0Slider.MinorTicks = [];
            app.LoadDataDropDownLabel = uilabel(app.UIFigure);
            app.LoadDataDropDownLabel.Text = 'Load Data';
            app.LoadDataDropDown = uidropdown(app.UIFigure);
            app.LoadDataDropDown.Items = {' ', 'Belgium', 'Denmark', 'Sweden', 'Spain', 'Italy', 'France'};
            app.LoadDataDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadDataDropDownValueChanged, true);
            app.LoadDataDropDown.Value = ' ';
            app.Rlock = uicheckbox(app.UIFigure);
            app.Rlock.ValueChangedFcn = createCallbackFcn(app, @RlockValueChanged, true);
            app.Rlock.Text = '';
            app.RendSpinner = uispinner(app.UIFigure);
            app.RendSpinner.Step = 0.1;
            app.RendSpinner.ValueDisplayFormat = '%.2f';
            app.RendSpinner.ValueChangedFcn = createCallbackFcn(app, @RendSpinnerValueChanged, true);
            app.RendSpinner.Value = 0.7;
            app.delayEditFieldLabel = uilabel(app.UIFigure);
            app.delayEditFieldLabel.Text = 'delay';
            app.delayInput = uieditfield(app.UIFigure, 'numeric');
            app.delayInput.Tooltip = {'Infection Fatality Rate. The fraction of all infected that dies.'};
            app.delayInput.Value = 20;
            app.serialintervalEditFieldLabel = uilabel(app.UIFigure);
            app.serialintervalEditFieldLabel.Text = 'serial interval';
            app.serialintervalEditField = uieditfield(app.UIFigure, 'numeric');
            app.serialintervalEditField.Tooltip = {'Infection Fatality Rate. The fraction of all infected that dies.'};
            app.serialintervalEditField.Value = 5;
            app.IFREditFieldLabel = uilabel(app.UIFigure);
            app.IFREditFieldLabel.Text = 'IFR';
            app.IFREditField = uieditfield(app.UIFigure, 'numeric');
            app.IFREditField.Tooltip = {'Infection Fatality Rate. The fraction of all infected that dies.'};
            app.IFREditField.Value = 0.01;
            app.varianceEditField_2Label = uilabel(app.UIFigure);
            app.varianceEditField_2Label.Text = 'variance';
            app.varianceInput = uieditfield(app.UIFigure, 'numeric');
            app.varianceInput.Tooltip = {'Infection Fatality Rate. The fraction of all infected that dies.'};
            app.varianceInput.Value = 20;
            app.fitButton = uibutton(app.UIFigure, 'push');
            app.fitButton.ButtonPushedFcn = createCallbackFcn(app, @fitButtonPushed, true);
            app.fitButton.Text = 'Fit';
            app.RstartSpinner = uispinner(app.UIFigure);
            app.RstartSpinner.ValueDisplayFormat = '%.2f';
            app.RstartSpinner.ValueChangedFcn = createCallbackFcn(app, @RstartSpinnerValueChanged, true);
            app.RstartSpinner.Value = 3;
            app.onsetSpinner = uispinner(app.UIFigure);
            app.onsetSpinner.ValueDisplayFormat = '%.2f';
            app.onsetSpinner.ValueChangedFcn = createCallbackFcn(app, @onsetSpinnerValueChanged, true);
            app.onsetSpinner.Value = 20;
            app.simButton = uibutton(app.UIFigure, 'push');
            app.simButton.ButtonPushedFcn = createCallbackFcn(app, @simButtonPushed, true);
            app.simButton.Text = 'Sim';
            app.fitInfectedButton = uibutton(app.UIFigure, 'push');
            app.fitInfectedButton.Text = 'Fit';
            app.RmodelDropDownLabel = uilabel(app.UIFigure);
            app.RmodelDropDownLabel.Text = 'R model';
            app.RmodelDropDown = uidropdown(app.UIFigure);
            app.RmodelDropDown.Items = {'sigmoid', 'exponential', 'step', 'logistic'};
            app.RmodelDropDown.ItemsData = {'sigmoid', 'exponential', 'step', 'genlog'};
            app.RmodelDropDown.ValueChangedFcn = createCallbackFcn(app, @RmodelDropDownValueChanged, true);
            app.RmodelDropDown.Value = 'sigmoid';
            app.N0SpinnerLabel = uilabel(app.UIFigure);
            app.N0SpinnerLabel.Text = 'N0';
            app.N0Spinner = uispinner(app.UIFigure);
            app.N0Spinner.Limits = [0 Inf];
            app.N0Spinner.ValueDisplayFormat = '%.1f';
            app.N0Spinner.ValueChangedFcn = createCallbackFcn(app, @N0SpinnerValueChanged, true);
            app.N0Spinner.Value = 1;
            app.casesPlot = uiaxes(app.UIFigure);
            zlabel(app.casesPlot, 'Z')
            app.casesPlot.XLim = [0 200];
            app.casesPlot.XTick = [20 40 60 80 100 120 140 160 180];
            app.RePlot = uiaxes(app.UIFigure);
            zlabel(app.RePlot, 'Z')
            app.RePlot.XLim = [0 200];
            app.RePlot.YLim = [0.1 10];
            app.RePlot.XTick = [20 40 60 80 100 120 140 160 180];
            app.RePlot.YTick = [0.1 0.5 1 5 10];
            app.RePlot.YScale = 'log';
            app.deathsPlot = uiaxes(app.UIFigure);
            xlabel(app.deathsPlot, 'days')
            zlabel(app.deathsPlot, 'Z')
            app.deathsPlot.XLim = [0 200];
            app.deathsPlot.XTick = [20 40 60 80 100 120 140 160 180];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_2017

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end